classdef CameraApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LogEdit                        matlab.ui.control.TextArea
        LogTextAreaLabel               matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ConfigurationTab               matlab.ui.container.Tab
        AvenirSpectrometerPanel        matlab.ui.container.Panel
        closeSpectrometer              matlab.ui.control.Button
        ConnectSpectrometer            matlab.ui.control.Button
        ElliptecPanel                  matlab.ui.container.Panel
        addressEditField               matlab.ui.control.EditField
        addressEditFieldLabel          matlab.ui.control.Label
        COMEditField                   matlab.ui.control.EditField
        COMEditFieldLabel              matlab.ui.control.Label
        closeEllButton                 matlab.ui.control.Button
        ConnectEllButton               matlab.ui.control.Button
        CameraPanel                    matlab.ui.container.Panel
        closeCameraButton              matlab.ui.control.Button
        ConnectCameraButton            matlab.ui.control.Button
        ThorlabMotorPanel              matlab.ui.container.Panel
        MotorAddressDropDown           matlab.ui.control.DropDown
        MotorAddressDropDownLabel      matlab.ui.control.Label
        ConnectStageButton             matlab.ui.control.Button
        CloseStageButton               matlab.ui.control.Button
        LockStageButton                matlab.ui.control.Button
        CameraTab                      matlab.ui.container.Tab
        CameraSettingPanel             matlab.ui.container.Panel
        recordwithspectrumButton       matlab.ui.control.Button
        MeasureIntegralEditField       matlab.ui.control.NumericEditField
        measureintegralButton          matlab.ui.control.Button
        windowEditField                matlab.ui.control.NumericEditField
        windowEditFieldLabel           matlab.ui.control.Label
        ycenterEditField               matlab.ui.control.NumericEditField
        ycenterEditFieldLabel          matlab.ui.control.Label
        xcenterEditField               matlab.ui.control.NumericEditField
        xcenterEditFieldLabel          matlab.ui.control.Label
        startrecordButton              matlab.ui.control.Button
        numberofframesEditField        matlab.ui.control.NumericEditField
        numberofframesEditFieldLabel   matlab.ui.control.Label
        TriggerDelayusEditField        matlab.ui.control.NumericEditField
        TriggerDelayusEditFieldLabel   matlab.ui.control.Label
        TriggerModeSwitch              matlab.ui.control.Switch
        TriggerModeSwitchLabel         matlab.ui.control.Label
        manualD4sButton                matlab.ui.control.Button
        logCheckBox                    matlab.ui.control.CheckBox
        D4sdynamicCheckBox             matlab.ui.control.CheckBox
        AutoExposure_EllButton         matlab.ui.control.Button
        thresholdEditField             matlab.ui.control.NumericEditField
        thresholdEditFieldLabel        matlab.ui.control.Label
        saveframeButton                matlab.ui.control.Button
        AutoExposureButton             matlab.ui.control.Button
        ExposuretimeButton             matlab.ui.control.Button
        ZoomButton                     matlab.ui.control.Button
        LiveButton                     matlab.ui.control.Button
        D4sCheckBox                    matlab.ui.control.CheckBox
        ColormapLabel                  matlab.ui.control.Label
        ColormapDropDown               matlab.ui.control.DropDown
        ExposureEditField              matlab.ui.control.NumericEditField
        usLabel                        matlab.ui.control.Label
        ZoomEditField                  matlab.ui.control.NumericEditField
        SpectrometerTab                matlab.ui.container.Tab
        getdarkButton                  matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        getonceButton                  matlab.ui.control.Button
        SettingPanel                   matlab.ui.container.Panel
        ApplyButton                    matlab.ui.control.Button
        TriggerSwitch                  matlab.ui.control.Switch
        TriggerSwitchLabel             matlab.ui.control.Label
        AveragingEditField             matlab.ui.control.NumericEditField
        AveragingEditFieldLabel        matlab.ui.control.Label
        ExposuretimemsEditField        matlab.ui.control.NumericEditField
        ExposuretimemsEditFieldLabel   matlab.ui.control.Label
        SpectrumFigure                 matlab.ui.control.UIAxes
        E                              matlab.ui.container.Tab
        EllipteccontrolPanel           matlab.ui.container.Panel
        ELLPosEditField                matlab.ui.control.NumericEditField
        getposButton                   matlab.ui.control.Button
        numberoffiltersEditField       matlab.ui.control.NumericEditField
        numberoffiltersEditFieldLabel  matlab.ui.control.Label
        filter4Button                  matlab.ui.control.Button
        filter3Button                  matlab.ui.control.Button
        filter2Button                  matlab.ui.control.Button
        filter1Button                  matlab.ui.control.Button
        ELLHomeButton                  matlab.ui.control.Button
        M2Tab                          matlab.ui.container.Tab
        runM2anlysisButton             matlab.ui.control.Button
        getM2framesButton              matlab.ui.control.Button
        saveM2Button                   matlab.ui.control.Button
        WavelengthEditField            matlab.ui.control.NumericEditField
        WavelengthumLabel              matlab.ui.control.Label
        StartPositionEditField         matlab.ui.control.NumericEditField
        StartPositionmmLabel           matlab.ui.control.Label
        M2EditField                    matlab.ui.control.NumericEditField
        M2EditFieldLabel               matlab.ui.control.Label
        getM2framesManualButton        matlab.ui.control.Button
        ZrEditField                    matlab.ui.control.NumericEditField
        ZrEditFieldLabel               matlab.ui.control.Label
        FindRayleighLengthButton       matlab.ui.control.Button
        SteplengthEditField            matlab.ui.control.NumericEditField
        SteplengthEditFieldLabel       matlab.ui.control.Label
        MinimalStepEditField           matlab.ui.control.NumericEditField
        MinimalStepmmEditFieldLabel    matlab.ui.control.Label
        Z0EditField                    matlab.ui.control.NumericEditField
        Z0EditFieldLabel               matlab.ui.control.Label
        W0EditField                    matlab.ui.control.NumericEditField
        W0EditFieldLabel               matlab.ui.control.Label
        FindWaistLocationButton        matlab.ui.control.Button
        InitialStepEditField           matlab.ui.control.NumericEditField
        InitialStepmmEditFieldLabel    matlab.ui.control.Label
        ThorlabStagePanel              matlab.ui.container.Panel
        PositionEditField              matlab.ui.control.EditField
        PositionEditField_2Label       matlab.ui.control.Label
        MovetoabsoluteButton           matlab.ui.control.Button
        MovetoPositionEditField        matlab.ui.control.NumericEditField
        JogsizeEditField               matlab.ui.control.NumericEditField
        JogsizeEditFieldLabel          matlab.ui.control.Label
        DownButton                     matlab.ui.control.Button
        UpButton                       matlab.ui.control.Button
    end

    
    properties (Access = public)
        Stage % Thorlab Stage object
        cam % camera object
        src
        cam_param
        cam_live_view
        MotorPool
        last_M2_data
        spectrometer

        ELL
        ELL_filters = 3

    end

    methods (Access = public)


        % function UpdateLog(app,varargin)
        %         % THIS FUNCTION UPDATES THE LOGBOX (ADDS A NEW LINE WITH THE NEW STRING)
        %         % varargin = either ("Message","Color") or ("Message") $
        %         switch nargin
        %             case 2
        %                 app.LogEdit.Value{end+1} = char(append(string(datetime),': ',convertCharsToStrings(varargin{1})));
        %             case 3
        %                 app.LogEdit.Value{end+1} = char(append(string(datetime),': ',convertCharsToStrings(varargin{1})));
        %                 app.LogEdit.BackgroundColor = varargin{2};
        %         end
        %         drawnow;
        % end

        function UpdateLog(app, varargin)
            % THIS FUNCTION UPDATES THE LOGBOX (ADDS A NEW LINE AT THE BEGINNING WITH THE NEW STRING)
            switch nargin
                case 2
                    newLine = char(append(string(datetime), ': ', convertCharsToStrings(varargin{1})));
                    app.LogEdit.Value = [{newLine}; app.LogEdit.Value(:)];
                case 3
                    newLine = char(append(string(datetime), ': ', convertCharsToStrings(varargin{1})));
                    app.LogEdit.Value = [{newLine}; app.LogEdit.Value(:)];
                    app.LogEdit.BackgroundColor = varargin{2};
            end
            drawnow;
        end


         function UpdateStagePosition(app)
            [Pos,ErrMsg]= app.Stage.GetPosition();
            if strcmp(ErrMsg.color,'red')
                app.UpdateLog(ErrMsg.txt,ErrMsg.color);
            else
                app.PositionEditField.Value = [num2str(round(Pos(1),4))];
                drawnow;
            end
         end


         function [params, x_fit, y_fit, params_range] = M2Fit(app, z, W2,n_low_points)
             % Create a fit in the shape : Y = A + B*(x-C)^2
             % Extract parameters
             % W02 = params(1);
             % M4_term = params(2);
             % z0 = params(3);

             if nargin < 4
                 n_low_points=1;
             end

             % Ensure z and W_squared are column vectors
             z = z(:);
             W2 = W2(:);

             % Initial guess for parameters [W0^2, M^4*(lambda/pi*W0)^2, z0]
             initial_guess = [min(W2),...
                 (max(W2)-min(W2))/(max(z)-min(z))^2,...
                 mean(z)];

             % Define the fitting function
             % fitfunc = @(params, z) params(1) + params(2)*(z - params(3)).^2;
             % Perform the fit
             % [params, ~] = lsqcurvefit(fitfunc, initial_guess, z, W2);

             % set weights
             % Sort y_data and get indices of n lowest values
             [~, sorted_indices] = sort(W2);
             lowest_indices = sorted_indices(1:n_low_points);

             % Create weight vector
             weights = ones(size(W2));
             weights(lowest_indices) = 300; % Assign high weight to lowest n points

             ft = fittype( 'a+b*(x-c)^2', 'independent', 'x', 'dependent', 'y' );
             opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
             opts.Display = 'Off';
             if n_low_points~=0
                 opts.Weights = weights;
             end
             opts.Lower = [0, 0, min(z)]; % Lower bounds
             opts.Upper = [max(W2), Inf, max(z)]; % Upper bounds
             opts.StartPoint = initial_guess;

             % preform the fit
             [fitted_curve, ~] = fit( z, W2, ft, opts );
             params = coeffvalues(fitted_curve);
             params_range = confint(fitted_curve,0.9);


             % Create new x values for the fit
             numPoints = 200;
             x_fit = linspace(min(z), max(z), numPoints);

             % Compute the fitted y values
             % y_fit = fitfunc(params, x_fit);
             y_fit = feval(fitted_curve,x_fit);


         end


         function ExpValue = SetAutoExposure(app)

             counter = 1;
             Ncounter = 30;

             % Cconfigurations
             ExpLimits = app.ExposureEditField.Limits;
             app.cam.src.AutoExposureTimeUpperLimit = ExpLimits(2) /2 ;
             app.cam.src.AutoExposureTimeLowerLimit = ExpLimits(1) * 100;
             app.cam.src.AutoFunctionProfile = "MinimizeGain";
             app.cam.src.AutoTargetBrightness = 0.8;

             while counter<=Ncounter

                 app.cam.src.ExposureAuto = "Once";
                 pause(0.2);
                 ExpValueTmp(counter) = app.cam.src.ExposureTime;
                 pause(0.1+ExpValueTmp(counter)/1e6);
                 % frame = app.cam.getFrame(); % TODO: need it?
                 pause(0.5);

                 disp(['Counter: ', num2str(counter), ', ExposureTime: ', num2str(app.cam.src.ExposureTime)]);

                 app.set_exposure(round( ExpValueTmp(counter) ))

                 % MaxVal = max(max(frame));

                 % TODO: check the condition
                 if counter > 1
                     TmpFlag = abs(ExpValueTmp(counter)-ExpValueTmp(counter-1))/ExpValueTmp(counter);
                     if TmpFlag<0.02
                         break
                     end
                 end
                 counter = counter + 1;
             end

             app.cam.src.ExposureAuto = "Off";
             ExpValue = ExpValueTmp(end);

         end


         function ExpValue = SetAutoExposure2(app)

             % run a binary serch on the exposure time until the maximum
             % value is in the deffined range
             up_limit_value = app.cam.src.PixelDynamicRangeMax; % 4095 for uint12

             upper_range = 0.90*up_limit_value;
             lower_range = 0.80*up_limit_value;
             max_burned_pixles = 10;
             max_exposure_allow = 5e5;

             ExpLimits = app.ExposureEditField.Limits;
             ExposureTimeMax = ExpLimits(2);
             ExposureTimeMin = ExpLimits(1);

             cur_min = ExposureTimeMin;
             cur_max = ExposureTimeMax;

             current_exposure = (cur_max + cur_min) / 2;


             while (current_exposure > ExposureTimeMin) && (current_exposure < ExposureTimeMax) ...
                     && (abs(cur_max - cur_min)/cur_max > 0.05) 

                 current_exposure = (cur_max + cur_min) / 2;
                 app.set_exposure(current_exposure);
                 pause( 0.1 + current_exposure/1e6 );

                 % if it is too high
                 if current_exposure > 5.1e5
                     break;
                 end
                 
                 % take a few images
                 for i=1:3
                    frame(i,:,:) = app.cam.getFrame;
                 end

                 max_val = max(frame(:));
                 count_max = sum(frame > upper_range, 'all') / size(frame,1);

                 if max_val > upper_range && count_max > max_burned_pixles
                     cur_max = current_exposure;
                 elseif max_val < lower_range
                     cur_min = current_exposure;
                 else
                     break;
                 end


             end

             % Backup in case there is a problem
             pause(0.1)
             frame = app.cam.getFrame;
             max_val = max(frame(:));
             if max_val > 0.9*up_limit_value && count_max > max_burned_pixles
                ExpValue = SetAutoExposure2(app);
                app.UpdateLog('Auto Exposure rerun', [1,1,0]);
             end


             ExpValue = current_exposure;

         end


         function set_exposure(app, time)

             app.ExposureEditField.Value = time;
             app.ExposuretimeButtonPushed();
             
         end


         function check_filter(app)

             up_limit_value = app.cam.src.PixelDynamicRangeMax; % 4095 for uint12

             upper_range = 0.95*up_limit_value;
             lower_range = 0.3*up_limit_value;
             max_burned_pixles = 10;
             
             curr_filter = app.get_current_filter;


             % take a few images
                 for i=1:3
                    frame(i,:,:) = app.cam.getFrame;
                 end

                 max_val = max(frame(:));
                 count_max = sum(frame > upper_range, 'all') / size(frame,1);

                 if max_val > upper_range && count_max > max_burned_pixles
                     limit_flag = app.filter_up(curr_filter);
                 elseif max_val < lower_range
                     limit_flag = app.filter_down(curr_filter);
                 else
                     % all good, continue
                     return;
                 end


             if limit_flag 
                 app.UpdateLog('Filter problem', [1 0 0]);
                 msgbox("Filter problem");
                 return
             

             else
                 app.check_filter();
             end


         end


         function autoExposure_with_filter_checking(app)

             exposure_high_thr = 3e5;
             exposure_low_thr = 2e4;


             app.ELL_filters = app.numberoffiltersEditField.Value;
             app.AutoExposureButtonPushed();
             exposure_time = app.ExposureEditField.Value;
             curr_filter = app.get_current_filter;

             if exposure_time > exposure_high_thr
                % needs more power to camera
                limit_flag = app.filter_down(curr_filter);

             elseif exposure_time < exposure_low_thr
                 % needs less power to camera
                 limit_flag = app.filter_up(curr_filter);

             else
                 % all good, continue
                 return;

             end


             if limit_flag 
                 app.UpdateLog('Filter problem', [1 0 0]);
                 msgbox("Filter problem");
                 return
             

             else

                 app.autoExposure_with_filter_checking();


             end


        end
        
        function update_filer_colors(app,filter_set)
            for i=1:4
                if i==filter_set
                    app.(strcat('filter', num2str(i), 'Button')).BackgroundColor = [0 1 1];
                else
                    app.(strcat('filter', num2str(i), 'Button')).BackgroundColor = [0.96 0.96 0.96];
                end
            end
            
        end


        function filter_num = get_current_filter(app)

            try
                filter_num = app.ELL.get_curr_filter;
            catch
                app.UpdateLog((err.message),'red');
            end
            
        end


        function max_reach = filter_up(app,curr_filter)

            if curr_filter >= app.ELL_filters
                % maximun filter, nothing to do
                max_reach = true;

            else
                app.(strcat('filter', num2str(curr_filter+1), 'ButtonPushed'));
                max_reach = false;
            end            
        end


        function min_reach = filter_down(app,curr_filter)

            if curr_filter <= 1
                % minimum filter, nothing to do
                min_reach = true;

            else
                app.(strcat('filter', num2str(curr_filter-1), 'ButtonPushed'));
                min_reach = false;
            end            
        end

        
        % try to increes the thr and see if wre get a big change
        function [D4s,thr] = D4s_dymanic_wraper(app,frame,last_thr)

            change_ratio = 1.5;
            incress_thr = 5;
            change_ratio = 1.5;            

            if nargin < 3
                last_thr = mean(frame, 'all');

            end
            % thr_to_check = (max_thr+last_thr)/2;

            [D4s_start,thr_start] = app.cam.get_D4sigma_dymanic(frame,last_thr);
            D4s_check = app.cam.get_D4sigma(frame,thr_start*incress_thr);
            % D4s_check = app.cam.get_D4sigma(frame,thr_to_check);

            if D4s_start.D4sigmaX >  D4s_check.D4sigmaX*change_ratio || ...
                    D4s_start.D4sigmaY >  D4s_check.D4sigmaY*change_ratio

                % thr is not optimal
                [D4s,thr_start] = app.D4s_dymanic_wraper(frame,thr_start * 1.5);
            

            else
                % D4s = D4s_start;
                % thr = thr_start;
            
            
            end

            [D4s,thr] = app.D4s_dymanic_wraper2(thr_start);


        end


        % take a few frames and see if wee get big deviations
        function [D4s,thr] = D4s_dymanic_wraper2(app,start_thr)


            frames2_check = 5;
            std_thr = 20;

            for i=1:frames2_check
                frame = app.cam.getFrame();
                [D4s,thr] = app.cam.get_D4sigma_dymanic(frame,start_thr);
                D4sX(i) = D4s.D4sigmaX;
                D4sY(i) = D4s.D4sigmaY;
            end

            stdX = std(D4sX);
            stdY = std(D4sY);

            if stdX >  std_thr || stdY >  std_thr

                [D4s,thr] = app.D4s_dymanic_wraper2(start_thr*1.5);

            else
                D4s = D4s;
                thr = thr;


            end


        end

        
        function frame = cleanFrame(app,frame_original)

            frame_original = squeeze(frame_original);

            % Display the image
            figure;
            imagesc(frame_original);
            axis square;
            colormap jet;

            % Create an interactive square ROI
            roi = drawrectangle('AspectRatio', 1, 'FixedAspectRatio', true);

            % Wait for the user to finish drawing
            wait(roi);
            

            % Get the position of the square
            position = round(roi.Position);
            close;

            % Create a mask of zeros
            mask = zeros(size(frame_original));

            % Set the chosen square area to 1 in the mask
            mask(position(2):position(2)+position(4)-1, position(1):position(1)+position(3)-1) = 1;

            % Apply the mask to the original image
            frame = frame_original .* mask;


        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LockStageButton
        function LockStageButtonPushed(app, event)
            app.UpdateLog('Attempting to initiate communication with Linear stages',[1 1 0]); % yellow
            app.Stage = ThorlabsStageController(app.MotorAddressDropDown.Value,0); % set connection

            if app.Stage.InitStatus


                RetMsg = app.Stage.InitStage(app.UIFigure); % homming stage

                if ~strcmp(RetMsg.color,'red') % no erros

                    app.ThorlabStagePanel.Enable = 'on';
                    app.UpdateLog('Communication initiation with Linear stages succeeded','green');
                    app.MovetoPositionEditField.Limits=[app.Stage.XStageLimits];


                    app.UpdateLog(RetMsg.txt,RetMsg.color);
                    app.CloseStageButton.Enable = 'on';
                    app.LockStageButton.Text = 'Locked';
                    app.LockStageButton.Enable = 'off';
                    app.LockStageButton.BackgroundColor = [0 1 0]; % green

                    if strcmp(app.LockStageButton.Text,'Locked') && ...
                        strcmp(app.ConnectCameraButton.Text, 'Connected') && ...
                        strcmp(app.ConnectEllButton.Text, 'Connected')

                        app.FindWaistLocationButton.Enable = 'on';
                    end


                    %% to fix the velosety problem

                    % velosity_req = 5;
                    % acceletation_req = 5;
                    % 
                    % app.Stage.StageX.VelParam.MaxVelocity = velosity_req;
                    % app.Stage.StageX.VelParam.Acceleration = acceletation_req;
                    % app.Stage.StageX.SetMoveToSetting();


                else
                    uialert(app.UIFigure ,"Initializing Linear stage controller failed!","ERROR");
                    app.UpdateLog('Communication initiation with Linear stages Failed','red')
                    app.UpdateLog(app.Stage.ErrMsg)


                end
            end
        end

        % Button pushed function: FindWaistLocationButton
        function FindWaistLocationButtonPushed(app, event)
            
            app.UpdateLog('Start finding the waist location')
            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Finding Waist Location", "Cancelable","on");

    
            startPos = app.StartPositionEditField.Value;
            initial_jog = app.InitialStepEditField.Value;
            limit_jog = app.MinimalStepEditField.Value;
            direction = true; % start by going up

            app.JogsizeEditField.Value = initial_jog;

            % Initialize empty arrays for x and y coordinates
            x = [];
            y = [];

            % move stage to start
            app.MovetoPositionEditField.Value = startPos;
            app.MovetoabsoluteButtonPushed;

            % auto exposure
            
            % app.AutoExposureButtonPushed;
            app.AutoExposure_EllButtonPushed;

            % take image and calculate width
            frame = app.cam.getFrame();
            % D4s = app.cam.get_D4sigma_dymanic(frame);
            % D4s = app.D4s_dymanic_wraper(frame);
            D4s = app.cam.get_D4sigma(frame);
            pre_width = D4s.D4sigmaX;


            % Update the plot data
            x = [x, str2double(app.PositionEditField.Value)];
            y = [y, pre_width];

            % setting the plot
            figure;
            
            % waist change
            subplot(1,2,1)
            title('Finding Waist');
            xlabel('Z [mm]');
            ylabel('D4\sigmaX');
            % Initialize an empty plot
            h = plot(x, y, 'x', 'MarkerSize', 15, 'MarkerFaceColor', 'r');

            % beam look
            subplot(1,2,2)
            image_obj = imagesc(frame);
            colormap jet;
            hold on;
            scatter_obj = plot(D4s.xel, D4s.yel, '*w', 'MarkerSize', 3);
            title_obj = title("D4sX:" + string(D4s.D4sigmaX) + " D4sY:" + string(D4s.D4sigmaY));
            xlim([min(D4s.xel)*0.9 max(D4s.xel)*1.1]);
            ylim([min(D4s.yel)*0.9 max(D4s.yel)*1.1]);
            axis square;
            hold off;


            current_width = pre_width;

            % find the intrest zone
            while pre_width >= current_width
                
                if d.CancelRequested
                    return;
                end
                
                pre_width = current_width;

                % jog up and calculate width
                app.UpButtonPushed;

                % auto exposure
                % app.AutoExposureButtonPushed;
                app.AutoExposure_EllButtonPushed;

                frame = app.cam.getFrame();

                % D4s = app.cam.get_D4sigma_dymanic(frame);
                % D4s = app.D4s_dymanic_wraper(frame);
                D4s = app.cam.get_D4sigma(frame);

                current_width = D4s.D4sigmaX;

                % Update the plot data
                subplot(1,2,1);
                hold on;
                x = [x, str2double(app.PositionEditField.Value)];
                y = [y, current_width];
                set(h, 'XData', x, 'YData', y);
                hold off;
                drawnow;

                subplot(1,2,2);
                set(image_obj, 'CData', frame);
                set(scatter_obj, 'XData', D4s.xel, 'YData', D4s.yel);
                set(title_obj, 'String', "D4sX:" + string(D4s.D4sigmaX) + " D4sY:" + string(D4s.D4sigmaY));
                xlim([min(D4s.xel)*0.9 max(D4s.xel)*1.1]);
                ylim([min(D4s.yel)*0.9 max(D4s.yel)*1.1]);
                axis square;
                drawnow;


            end

            % start taking smaller steps
            app.JogsizeEditField.Value = app.JogsizeEditField.Value / 2;

            
            while app.JogsizeEditField.Value >= limit_jog

                if d.CancelRequested
                    return;
                end

                if pre_width < current_width % switch side
                    direction = ~direction;
                end

                % jog
                if direction % jog up
                    app.UpButtonPushed;

                else % jog down
                    app.DownButtonPushed;
                end

                pre_width = current_width;

                % calculate width
                % app.AutoExposureButtonPushed;
                app.AutoExposure_EllButtonPushed;

                frame = app.cam.getFrame();
                D4s = app.cam.get_D4sigma(frame);
                current_width = D4s.D4sigmaX;

                % Update the plot data
                subplot(1,2,1);
                hold on;
                x = [x, str2double(app.PositionEditField.Value)];
                y = [y, current_width];
                set(h, 'XData', x, 'YData', y);
                hold off;
                drawnow;

                subplot(1,2,2);
                set(image_obj, 'CData', frame);
                set(scatter_obj, 'XData', D4s.xel, 'YData', D4s.yel);
                set(title_obj, 'String', "D4sX:" + string(D4s.D4sigmaX) + " D4sY:" + string(D4s.D4sigmaY));
                xlim([min(D4s.xel)*0.9 max(D4s.xel)*1.1]);
                ylim([min(D4s.yel)*0.9 max(D4s.yel)*1.1]);
                axis square;
                drawnow;

                % taking smaller steps
                app.JogsizeEditField.Value = app.JogsizeEditField.Value / 2;
            end


            % Done
            close(d)
            app.UpdateLog('DONE finding the waist location')
            app.W0EditField.Value = current_width;
            app.Z0EditField.Value = str2double(app.PositionEditField.Value);

            % app.FindWaistLocationButton.Enable = 'off';
            % app.FindWaistLocationButton.Text = 'Done';
            app.FindWaistLocationButton.BackgroundColor = [0 1 0]; % green
            app.FindRayleighLengthButton.Enable = 'on';

        end

        % Button pushed function: CloseStageButton
        function CloseStageButtonPushed(app, event)

            app.Stage.MoveToAbsPosition(0); % return to home possition
            RetMsg = app.Stage.CloseComm();
            if strcmp(RetMsg.color,'green') || strcmp(RetMsg.color,'blue')
                RetMsg.color = 'white'; % if shutdown sucessfull = mark it white to let user know "com not open"

                app.LockStageButton.BackgroundColor = [1 0 0]; % red
                app.LockStageButton.Text = 'Lock';
                app.ConnectStageButton.Enable = "on";
                app.ConnectStageButton.Text = 'Connect';
                app.ConnectStageButton.BackgroundColor = [1 0 0]; % red
            end
            app.UpdateLog(RetMsg.txt,RetMsg.color);
            app.Stage = [];

            
        end

        % Button pushed function: ConnectCameraButton
        function ConnectCameraButtonPushed(app, event)

            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Initializing connection to the Camera");

            app.cam = camera();

            if app.cam.InitStatus

                app.CameraSettingPanel.Enable = "on";
                app.UpdateLog('Camera Connected', [0 1 0]);
                app.ConnectCameraButton.Text = 'Connected';
                app.ConnectCameraButton.Enable = "off";
                app.ConnectCameraButton.BackgroundColor = [0 1 0];

                app.ZoomEditField.Value = min([app.cam.param.Height app.cam.param.Width]);
                app.ZoomEditField.Limits = [8, min([app.cam.param.Height app.cam.param.Width])];

                app.closeCameraButton.Enable = 'on';

                if strcmp(app.LockStageButton.Text,'Locked') && ...
                    strcmp(app.ConnectCameraButton.Text, 'Connected') && ...
                    strcmp(app.ConnectEllButton.Text, 'Connected')

                        app.FindWaistLocationButton.Enable = 'on';
                end

            else
                app.UpdateLog(app.cam.msg.txt, app.cam.msg.color);
            end


        end

        % Button pushed function: LiveButton
        function LiveButtonPushed(app, event)
            if app.cam.InitStatus

                app.cam_live_view = figure;
                frame = app.cam.getFrame();
                img_ax = axes;

                image = imagesc(img_ax,frame);

                colormap(img_ax, app.ColormapDropDown.Value);
                set(gca, 'CLim', [0 app.cam.src.PixelDynamicRangeMax]);
                colorbar;
                pbaspect([size(frame,2) size(frame,1) 1]);

                hold on
                elip = plot(1:app.cam.param.Width, 1:app.cam.param.Width,...
                    'LineStyle','none', 'Marker','*', 'MarkerEdgeColor',[1 1 1],...
                    'MarkerSize', 3);
                hold off
                ylim=get(gca,'ylim');
                xlim=get(gca,'xlim');

                if app.D4sCheckBox.Value
                    textData = {'D4\sigmaX: ', 'D4\sigmaY: '};
                else
                    textData = ' ';
                end

                txtH = text(xlim(1)+100, ylim(1)+200, textData, 'FontSize',14,'Color','white');


                while ishandle(app.cam_live_view)

                    frame = app.cam.getFrame();

                    thr = app.thresholdEditField.Value;
                    if app.D4sCheckBox.Value
                        if ~app.D4sdynamicCheckBox.Value
                            D4sigma = app.cam.get_D4sigma(frame,thr);
                        else
                            % [D4sigma,thr] = app.cam.get_D4sigma_dymanic(frame);
                            [D4sigma,thr] = app.D4s_dymanic_wraper(frame);

                            app.thresholdEditField.Value = thr;
                        end

                        elip.XData = D4sigma.xel;
                        elip.YData = D4sigma.yel;
                        txtH.String = {['D4\sigmaX: ' num2str(round(D4sigma.D4sigmaX) ) '[pxl]'...
                            '(' num2str(round(D4sigma.D4sigmaX*app.cam.param.pxl_length) ) '[um])' ],...
                            ['D4\sigmaY: ' num2str(round(D4sigma.D4sigmaY)) '[pxl]'...
                            '(' num2str(round(D4sigma.D4sigmaY*app.cam.param.pxl_length) ) '[um])']};
                    else
                        try
                            elip.XData = [];
                            elip.YData = [];
                            txtH.String = [];
                        catch
                            continue;
                        end
                    end

                    if app.logCheckBox.Value
                            frame = 10.^frame;
                    end

                    image.CData = frame;

                    drawnow;

                end

                app.cam_live_view = [];

            end
        end

        % Button pushed function: closeCameraButton
        function closeCameraButtonPushed(app, event)
            msg = app.cam.closeConnection();

            if msg.color == [0 1 0] % green
                app.CameraSettingPanel.Enable = "off";
                app.closeCameraButton.Enable = "off";
                app.ConnectCameraButton.Enable = 'on';
                app.ConnectCameraButton.Text = 'Connect';
                app.ConnectCameraButton.BackgroundColor = [1 0 0]; % red
            end
            app.UpdateLog(msg.txt,msg.color);
            app.cam = [];
        end

        % Value changed function: ExposureEditField
        function ExposureEditFieldValueChanged(app, event)

        end

        % Button pushed function: ConnectStageButton
        function ConnectStageButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Initializing connection to motor controllers");
            TempObj = tlDCMotor();                  % Temp object to get list of motors
            SerialNumbers = TempObj.GetMotorList(); % get all available serial numbers
            TempArray = startsWith(SerialNumbers , '70') + startsWith(SerialNumbers , '71') + startsWith(SerialNumbers , '44'); % all benchtop serial prefix.
            SerialNumbers = SerialNumbers(~logical(TempArray));
            app.MotorPool = {};
            for j = 1:length(SerialNumbers)
                % open motors
                % Put in temp "pool of motors"
                d.Value = j/length(SerialNumbers);
                try
                    app.MotorPool{end+1} = tlDCMotor();
                    app.MotorPool{end}.Open(SerialNumbers{j}); % Open tlDCMotor Motor
                    app.MotorPool{end}.Initialize();
                    app.MotorPool{end}.GetSetting;
                    app.UpdateLog(append('Communication Initiation with Thorlabs ', num2str(SerialNumbers{j}) ,' Succeeded'),'green');
                catch err
                    app.UpdateLog(append('Communication Initiation with Thorlabs ', num2str(SerialNumbers{j}) ,' Failed, ',err.message),'red');
                end
            end
            close(d);


            if ~isempty(SerialNumbers)
                % Initial population of variables
                app.MotorAddressDropDown.Items = SerialNumbers;
                app.MotorAddressDropDown.ItemsData = app.MotorPool;
            end
            TempObj.Close;

            app.ConnectStageButton.Enable = 'off';
            app.ConnectStageButton.BackgroundColor = [0 1 0]; % green
            app.ConnectStageButton.Text = 'Connected';

            app.MotorAddressDropDown.Enable = 'on';
            app.LockStageButton.Enable = 'on';


        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            stpsize = app.JogsizeEditField.Value;
            app.Stage.MoveJog(stpsize);
            app.UpdateStagePosition();
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            stpsize = app.JogsizeEditField.Value;
            app.Stage.MoveJog(-stpsize);
            app.UpdateStagePosition();
        end

        % Value changed function: MovetoPositionEditField
        function MovetoPositionEditFieldValueChanged(app, event)

        end

        % Button pushed function: MovetoabsoluteButton
        function MovetoabsoluteButtonPushed(app, event)
            pos = app.MovetoPositionEditField.Value;
            MSG=app.Stage.MoveToAbsPosition(pos);
            app.UpdateLog(MSG.txt,MSG.color);
            app.UpdateStagePosition();


        end

        % Button pushed function: FindRayleighLengthButton
        function FindRayleighLengthButtonPushed(app, event)
            app.UpdateLog('Start finding the Rayleigh Length');
            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Finding Rayleigh Length", "Cancelable","on");
            
            w_r = app.W0EditField.Value * sqrt(2);

            % set jog size
            app.JogsizeEditField.Value = app.SteplengthEditField.Value;

            % move to the waist location
            app.MovetoPositionEditField.Value = app.Z0EditField.Value;
            app.MovetoabsoluteButtonPushed;
            current_width = app.W0EditField.Value;

            while current_width < w_r

                if d.CancelRequested
                    return;
                end

                % jog up and calculate waist
                app.UpButtonPushed;
                % app.AutoExposureButtonPushed;
                app.AutoExposure_EllButtonPushed
                % D4s = app.cam.get_D4sigma(app.cam.getFrame());
                D4s = app.D4s_dymanic_wraper(app.cam.getFrame());

                current_width = D4s.D4sigmaX;

            end

            % DONE
            close(d);
            app.UpdateLog('DONE finding the Rayleigh Length');
            app.ZrEditField.Value = str2num(app.PositionEditField.Value);

            % app.FindRayleighLengthButton.Enable = 'off';
            % app.FindRayleighLengthButton.Text = 'Done';
            app.FindRayleighLengthButton.BackgroundColor = [0 1 0]; % green
            app.getM2framesManualButton.Enable = 'on';

        end

        % Value changed function: ZoomEditField
        function ZoomEditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            zoom = app.ZoomEditField.Value;

            if ~isempty(app.cam.device)
                tmpRes = get(app.cam.device, 'VideoResolution');
                MaxWidth = tmpRes(1);
                MaxHeight = tmpRes(2);
                Left = (MaxWidth - zoom)/2;
                Top = (MaxHeight - zoom)/2;
                stop(app.cam.device)
                app.cam.device.ROIPosition=[Left, Top, zoom, zoom];
                start(app.cam.device)
                
                if ishandle(app.cam_live_view)
                    xlim(gca, [1 zoom]);
                    ylim(gca, [1 zoom]);
                end

                app.UpdateLog('Zoom changed');
            end
        end

        % Button pushed function: ExposuretimeButton
        function ExposuretimeButtonPushed(app, event)
            value = app.ExposureEditField.Value;
            if ~isempty(app.cam)
                app.cam.src.ExposureTime = value;
                app.UpdateLog(['Exposure Time changed to ' num2str(value)]);
            end
        end

        % Button pushed function: getM2framesManualButton
        function getM2framesManualButtonPushed(app, event)
                     
            % claculate the z position we want to measure based on Z0 and Zr
            % MAKE SURE that the camera move is twice from the stage move
            % for each position: (1) do auto exposue (2) take 5 images 
            % (3) take the mean frame (4) calculate D4s
            % create a fit to the the in the form of: W = A + B*z + C*z^2
            % plot D4s vs Z_pos and the fit
            % calculate M2 from the fit coeffitions

            % NOTES
            % https://www.redalyc.org/journal/474/47471684003/html/
          

            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"M2 data collect", 'Cancelable', 'on');

            z0 = app.Z0EditField.Value;
            zr = app.ZrEditField.Value;
            d_zr = abs(zr - z0);

            z1 = floor(abs(z0 - app.Stage.XStageLimits(1)) / d_zr);
            z2 = floor(abs(z0 - app.Stage.XStageLimits(2)) / d_zr);
            zr_max = min(z1,z2);
            
            % building the location where to measure
            % z_pos = [0.1 0.3 0.5 2 2.1 2.2 4 4.1 4.2]; % oporational
            z_pos = [0.1 0.3 0.5 2 2.1 2.2 3 3.1 3.2]; 
            % z_pos = [-0.3 0 0.2 2 2.1 3 3.1 4 4.1];
            z_pos = [-flip(z_pos) 0 z_pos];
            z_pos = z0 + d_zr.* z_pos;

            if max(z_pos) > max(app.MovetoPositionEditField.Limits) ||...
                    min(z_pos) < min(app.MovetoPositionEditField.Limits)

                app.UpdateLog('requested position is out of range', [1 0 0]);
                return
            end


            % setting the plot
            figure;

            subplot(1,3,1)
            title('Waist');
            xlabel('Z [mm]');
            ylabel('D4\sigmaX');
            % Initialize an empty plot
            x = [];
            yx= [];
            yy = [];
            h = plot(x, yx, 'x', 'MarkerSize', 15, 'MarkerFaceColor', 'r');

            subplot(1,3,2)
            title('Waist');
            xlabel('Z [mm]');
            ylabel('D4\sigmaY');
            % Initialize an empty plot
            x = [];
            y= [];
            h = plot(x, yy, 'x', 'MarkerSize', 15, 'MarkerFaceColor', 'r');


            subplot(1,3,3)
            image_obj = imagesc([]);
            colormap jet;
            hold on;
            scatter_obj = plot(x, yx, '*w', 'MarkerSize', 3);
            title_obj = title([]);
            hold off;

            
            for i = 1:length(z_pos)

                if d.CancelRequested
                    return;
                end
                
                d.Value = i / length(z_pos);
                d.Message = sprintf('Processing step %d of %d', i, length(z_pos));
                drawnow;

                % move stage
                app.Stage.MoveToAbsPosition(z_pos(i));

                %%% set ND filter manually %%%

                % Create figure and axes
                temp_cam_live_view = figure('Position', [100 100 400 400]);
                img_ax = axes('Parent', temp_cam_live_view, 'Position', [0.13 0.3 0.775 0.65]);
                frame = app.cam.getFrame();
                img_handle = imagesc(img_ax, frame);
                colormap jet;

                set(gca, 'CLim', [0 app.cam.src.PixelDynamicRangeMax]);
                colorbar;
                
                % Add an 'OK' button below the image
                btn = uicontrol('Parent', temp_cam_live_view, ...
                                'Style', 'pushbutton', ...
                                'String', 'OK', ...
                                'FontSize', 12, ...
                                'Position', [150 40 100 40], ...
                                'Callback', @(src, event) close(temp_cam_live_view));
                
                % Live update loop
                while ishandle(temp_cam_live_view)
                    
                    try
                        frame = app.cam.getFrame();        % Get new frame each loop
                        img_handle.CData = frame;          % Update image data
                        drawnow;                           % Update the figure window
                    end
                end
                temp_cam_live_view = [];

                pause(0.1);

                % take 5 frames and mean them
                for j = 1:5
                    frame_tmp(:,:,j) = app.cam.getFrame();
                    pause(0.1);
                end
                frame_original = mean(frame_tmp, 3);
                saved_frames(i,:,:) = frame_original;

                frame = frame_original;

                % get D4s and store in array
                % D4s(i) = app.cam.get_D4sigma_dymanic(frame);
                % D4s(i) = app.D4s_dymanic_wraper(frame);
                D4s(i) = app.cam.get_D4sigma(frame);

                % Update the plot data
                subplot(1,3,3);
                image_obj = imagesc(frame);
                colormap jet;
                hold on;
                scatter_obj = plot(D4s(i).xel, D4s(i).yel, '*w', 'MarkerSize', 3);
                title_obj = title("D4sX:" + string(D4s(i).D4sigmaX) +...
                                        " D4sY:" + string(D4s(i).D4sigmaY));
                xlim([min(D4s(i).xel)*0.9 max(D4s(i).xel)*1.1]);
                ylim([min(D4s(i).yel)*0.9 max(D4s(i).yel)*1.1]);
                axis square;
                hold off;
                drawnow;


                subplot(1, 3, 1);
                x = [x, z_pos(i)];
                yx = [yx, D4s(i).D4sigmaX];
                plot(x, yx, 'x', 'MarkerSize', 15, 'MarkerFaceColor', 'r'); % Update plot data
                xlim([min(x), max(x)+1e-2]); % Ensure x-axis covers all data points
                ylim([min(yx)*0.9, max(yx)*1.1]); % Adjust y-axis with some padding
                title("W2-X")
                drawnow;

                subplot(1, 3, 2);
                yy = [yy, D4s(i).D4sigmaY];
                plot(x, yy, 'x', 'MarkerSize', 15, 'MarkerFaceColor', 'r'); % Update plot data
                xlim([min(x), max(x)+1e-2]); % Ensure x-axis covers all data points
                ylim([min(yy)*0.9, max(yy)*1.1]); % Adjust y-axis with some padding
                title("W2-Y")
                drawnow;

            end

            % building data struct to save

            % app.last_M2_data.lambda = lambda;
            % app.last_M2_data.raw.D4s = D4s;
            app.last_M2_data.raw.z_pos = z_pos;
            % app.last_M2_data.raw.W2x = W2x;
            % app.last_M2_data.raw.W2y = W2y;
            % app.last_M2_data.raw.x_axsis = x_axsis;
            app.last_M2_data.raw.frames = saved_frames;


            % Start anlysis

            lambda = app.WavelengthEditField.Value * 1e-3; % [mm]

            % normalize around 0
            x_axsis = ( z_pos - z0 ) * 2; % 1mm of the stage is twice the size for the light
            W2x = ( [D4s.D4sigmaX]./2 .* app.cam.param.pxl_length * 1e-3 ) .^2; % [mm^2]
            W2y = ( [D4s.D4sigmaY]./2 .* app.cam.param.pxl_length * 1e-3 ) .^2; % [mm^2]


            % M2 X axis
            n_point_near_w0 = 0;
            [Xparams, x_new, W2x_fit,Xparams_range] = app.M2Fit(x_axsis, W2x,n_point_near_w0);
            M2x = sqrt( Xparams(2) * (pi * sqrt(Xparams(1)) / lambda).^2 );
            
            % M2x_Range
            for i=1:2
                for j=1:2
                    M2x_values(i,j) = sqrt( Xparams_range(i,2) .* (pi .* sqrt(Xparams_range(j,1)) ./ lambda).^2 );
                end
            end
            M2x_max = max(M2x_values(:));
            M2x_min = min(M2x_values(:));
            
            M2x_range = max( abs(M2x-M2x_min), abs(M2x-M2x_max) );

            figure("Name", "M2 X calculation measurment");
            plot(x_axsis,sqrt(W2x),'x','Color',[1 0 0],'DisplayName','data');
            hold on
            plot(x_new,sqrt(W2x_fit),'Color',[0 0 1],'DisplayName','fit');
            hold off;
            xlabel('Z [mm]');
            ylabel('W_x [mm]');
            title(['M2 - X = ' num2str(M2x) '+-' num2str(M2x_range)])

            % M2 Y axsis
            [Yparams, x_new, W2y_fit,Yparams_range] = app.M2Fit(x_axsis, W2y,n_point_near_w0);
            M2y = sqrt( Yparams(2) * (pi * sqrt(Yparams(1)) / lambda).^2 );

            % M2y_Range
            for i=1:2
                for j=1:2
                    M2y_values(i,j) = sqrt( Yparams_range(i,2) .* (pi .* sqrt(Yparams_range(j,1)) ./ lambda).^2 );
                end
            end
            M2y_max = max(M2y_values(:));
            M2y_min = min(M2y_values(:));
            M2y_range = max( abs(M2y-M2y_min), abs(M2y-M2y_max) );

            figure("Name", "M2 Y calculation measurment");
            plot(x_axsis,sqrt(W2y),'x','Color',[1 0 0],'DisplayName','data');
            hold on
            plot(x_new,sqrt(W2y_fit),'Color',[0 0 1],'DisplayName','fit');
            hold off;
            xlabel('Z [mm]');
            ylabel('W_y [mm]');
            title(['M2 - Y = ' num2str(M2y) '+-' num2str(M2y_range)])


            app.last_M2_data.lambda = lambda;
            app.last_M2_data.raw.D4s = D4s;
            app.last_M2_data.raw.z_pos = z_pos;
            app.last_M2_data.raw.W2x = W2x;
            app.last_M2_data.raw.W2y = W2y;
            app.last_M2_data.raw.x_axsis = x_axsis;
            app.last_M2_data.raw.frames = saved_frames;

            app.last_M2_data.fit.x_axsis = x_new;
            app.last_M2_data.fit.W2x = W2x_fit;
            app.last_M2_data.fit.W2y = W2y_fit;
            app.last_M2_data.fit.M2x = M2x;
            app.last_M2_data.fit.M2y = M2y;
            app.last_M2_data.fit.M2x_range = M2x_range;
            app.last_M2_data.fit.M2y_range = M2y_range;
            
        end

        % Button pushed function: AutoExposureButton
        function AutoExposureButtonPushed(app, event)
           
            if ~isempty(app.cam)
                app.AutoExposureButton.Enable = 'off';
                % ExpValue = app.SetAutoExposure();
                ExpValue = app.SetAutoExposure2();

                app.UpdateLog('Auto Expoure it set', [0 1 0]);
                app.AutoExposureButton.Enable = 'on';
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            d = uiprogressdlg(app.UIFigure,"Title",'Please wait - closing connections');
            
            if strcmp(app.LockStageButton.Text,'Locked')
                app.CloseStageButtonPushed;
            end
            
            if strcmp(app.ConnectCameraButton.Text, 'Connected')
                app.closeCameraButtonPushed;
            end

            if strcmp(app.ConnectEllButton.Text, 'Connected')
                app.closeEllButton;
            end 


            if strcmp(app.ConnectSpectrometer.Text, 'Connected')
                app.closeSpectrometer;
            end 

            delete(app)
            
        end

        % Button pushed function: saveM2Button
        function saveM2ButtonPushed(app, event)
            % Get the data you want to save
            M2_Data = app.last_M2_data;

            % Open a dialog box for the user to choose where to save the file
            [file, path] = uiputfile('*.mat', 'Save Data As');

            if file ~= 0
                % User selected a file, so save the data
                fullPath = fullfile(path, file);
                save(fullPath, 'M2_Data');

                % Optionally, display a message to confirm the save
                msgbox('Data saved successfully!', 'Save Complete');
            end
        end

        % Button pushed function: saveframeButton
        function saveframeButtonPushed(app, event)
            data2save = app.cam.getFrame();

            % Open a dialog box for the user to choose where to save the file
            [file, path] = uiputfile('*.mat', 'Save Data As');

            if file ~= 0
                % User selected a file, so save the data
                fullPath = fullfile(path, file);
                save(fullPath, 'data2save');

                % Optionally, display a message to confirm the save
                msgbox('Data saved successfully!', 'Save Complete');
            end

        end

        % Button pushed function: ConnectEllButton
        function ConnectEllButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Initializing connection to the Elliptec motor");
            
            try

                COM = app.COMEditField.Value;
                address = app.addressEditField.Value;
                app.ELL = Elliptec(COM,address);

                if app.ELL.InitStatus

                    app.EllipteccontrolPanel.Enable = "on";
                    app.UpdateLog('Elliptec motor Connected', [0 1 0]);
                    app.ConnectEllButton.Text = 'Connected';
                    app.ConnectEllButton.Enable = "off";
                    app.ConnectEllButton.BackgroundColor = [0 1 0];

                    app.COMEditField.Enable = "off";
                    app.addressEditField.Enable = "off";

                    app.closeEllButton.Enable = 'on';

                    if strcmp(app.LockStageButton.Text,'Locked') && ...
                            strcmp(app.ConnectCameraButton.Text, 'Connected') && ...
                            strcmp(app.ConnectEllButton.Text, 'Connected')

                        app.FindWaistLocationButton.Enable = 'on';

                    end

                    close(d);

                    % homming the stage
                    app.ELLHomeButtonPushed();


                else
                    app.UpdateLog(app.ELL.msg.txt, app.ELL.msg.color);
                end


            catch err
                    app.UpdateLog((err.message),'red');
            end
   
        end

        % Button pushed function: ELLHomeButton
        function ELLHomeButtonPushed(app, event)
            
            try
                d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Preform Homming to Elliptec motor");
                app.ELL.home()
                app.UpdateLog(app.ELL.msg.txt, app.ELL.msg.color);
                app.update_filer_colors(1);
            catch err
                app.UpdateLog((err.message),'red');
            end


        end

        % Button pushed function: closeEllButton
        function closeEllButtonPushed(app, event)
            app.ELL.closeConnection();
            msg = app.ELL.msg;

            if msg.color == [0 1 0] % green
                app.EllipteccontrolPanel.Enable = "off";
                app.closeEllButton.Enable = "off";
                app.ConnectEllButton.Enable = 'on';
                app.ConnectEllButton.Text = 'Connect';
                app.ConnectEllButton.BackgroundColor = [1 0 0]; % red

                app.COMEditField.Enable = "on";
                app.addressEditField.Enable = "on";

            end
            app.UpdateLog(msg.txt,msg.color);
            app.ELL = [];
        end

        % Value changed function: numberoffiltersEditField
        function numberoffiltersEditFieldValueChanged(app, event)
            value = app.numberoffiltersEditField.Value;
            app.ELL_filters = value;

            for i=1:4
                if i>value
                    app.(strcat('filter', num2str(i), 'Button')).Enable = 'off';
                else
                    app.(strcat('filter', num2str(i), 'Button')).Enable = 'on';
                end

            end
        end

        % Button pushed function: filter1Button
        function filter1ButtonPushed(app, event)
            try
                err_flag = app.ELL.move2filter(1);
                app.UpdateLog(app.ELL.msg.txt,app.ELL.msg.color);

                % update button colors
                if ~err_flag
                    app.update_filer_colors(1);
                end
                    
            catch err
                app.UpdateLog((err.message),'red');
            end

        end

        % Button pushed function: filter2Button
        function filter2ButtonPushed(app, event)
            try
                err_flag = app.ELL.move2filter(2);
                app.UpdateLog(app.ELL.msg.txt,app.ELL.msg.color);

                % update button colors
                if ~err_flag
                    app.update_filer_colors(2);
                end
                    
            catch err
                app.UpdateLog((err.message),'red');
            end

        end

        % Button pushed function: filter3Button
        function filter3ButtonPushed(app, event)
            try
                err_flag = app.ELL.move2filter(3);
                app.UpdateLog(app.ELL.msg.txt,app.ELL.msg.color);

                % update button colors
                if ~err_flag
                    app.update_filer_colors(3);
                end
                    
            catch err
                app.UpdateLog((err.message),'red');
            end

        end

        % Button pushed function: filter4Button
        function filter4ButtonPushed(app, event)
            try
                err_flag = app.ELL.move2filter(4);
                app.UpdateLog(app.ELL.msg.txt,app.ELL.msg.color);

                % update button colors
                if ~err_flag
                    app.update_filer_colors(4);
                end
                    
            catch err
                app.UpdateLog((err.message),'red');
            end

        end

        % Button pushed function: AutoExposure_EllButton
        function AutoExposure_EllButtonPushed(app, event)
            % if we are in trigger mode, no need to auto exposure
            Trigger_on = app.TriggerModeSwitch.Value;

            % REMOVE
            Trigger_on = 'On';

            if ~strcmp(Trigger_on,'On') 
                app.autoExposure_with_filter_checking();
            
                
            else
                % no change to the exposure, only look for the optimal
                % filter
                app.check_filter();
            
            
            end
        end

        % Button pushed function: getposButton
        function getposButtonPushed(app, event)
            try 
                num = app.ELL.get_curr_filter();
                app.ELLPosEditField.Value = num;
                app.UpdateLog(app.ELL.msg.txt,app.ELL.msg.color);
            catch err
                app.UpdateLog((err.message),'red');
            end
        end

        % Value changed function: D4sCheckBox
        function D4sCheckBoxValueChanged(app, event)
            value = app.D4sCheckBox.Value;
            
        end

        % Value changed function: D4sdynamicCheckBox
        function D4sdynamicCheckBoxValueChanged(app, event)
            value = app.D4sdynamicCheckBox.Value;
            
        end

        % Button pushed function: manualD4sButton
        function manualD4sButtonPushed(app, event)
            

            frame = app.cleanFrame(app.cam.getFrame);
            % [D4s,thr] = app.D4s_dymanic_wraper(frame);
            D4s = app.cam.get_D4sigma(frame,20);
            % app.thresholdEditField.Value = thr;

            % display

            image_obj = imagesc(frame);
            colormap jet;
            hold on;
            scatter_obj = plot(D4s.xel, D4s.yel, '*w', 'MarkerSize', 3);
            title_obj = title("D4sX:" + string(D4s.D4sigmaX) +...
                " D4sY:" + string(D4s.D4sigmaY));
            xlim([min(D4s.xel)*0.7 max(D4s.xel)*1.3]);
            ylim([min(D4s.yel)*0.9 max(D4s.yel)*1.1]);
            axis square;
            hold off;

        end

        % Button pushed function: getM2framesButton
        function getM2framesButtonPushed(app, event)
                       
            % claculate the z position we want to measure based on Z0 and Zr
            % MAKE SURE that the camera move is twice from the stage move
            % for each position: (1) do auto exposue (2) take 5 images 
            % (3) take the mean frame (4) calculate D4s
            % create a fit to the the in the form of: W = A + B*z + C*z^2
            % plot D4s vs Z_pos and the fit
            % calculate M2 from the fit coeffitions

            % NOTES
            % https://www.redalyc.org/journal/474/47471684003/html/
          

            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"M2 data collect", 'Cancelable', 'on');

            z0 = app.Z0EditField.Value;
            zr = app.ZrEditField.Value;
            d_zr = abs(zr - z0);

            z1 = floor(abs(z0 - app.Stage.XStageLimits(1)) / d_zr);
            z2 = floor(abs(z0 - app.Stage.XStageLimits(2)) / d_zr);
            zr_max = min(z1,z2);
            
            % building the location where to measure
            % z_pos = [0.1 0.3 0.5 2 2.1 2.2 4 4.1 4.2]; % oporational
            z_pos = [0.1 0.3 0.5 2 2.1 2.2 3 3.1 3.2]; 
            % z_pos = [-0.3 0 0.2 2 2.1 3 3.1 4 4.1];
            z_pos = [-flip(z_pos) 0 z_pos];
            z_pos = z0 + d_zr.* z_pos;

            if max(z_pos) > max(app.MovetoPositionEditField.Limits) ||...
                    min(z_pos) < min(app.MovetoPositionEditField.Limits)

                app.UpdateLog('requested position is out of range', [1 0 0]);
                return
            end


            % setting the plot
            figure;

            subplot(1,3,1)
            title('Waist');
            xlabel('Z [mm]');
            ylabel('D4\sigmaX');
            % Initialize an empty plot
            x = [];
            yx= [];
            yy = [];
            h = plot(x, yx, 'x', 'MarkerSize', 15, 'MarkerFaceColor', 'r');

            subplot(1,3,2)
            title('Waist');
            xlabel('Z [mm]');
            ylabel('D4\sigmaY');
            % Initialize an empty plot
            x = [];
            y= [];
            h = plot(x, yy, 'x', 'MarkerSize', 15, 'MarkerFaceColor', 'r');


            subplot(1,3,3)
            image_obj = imagesc([]);
            colormap jet;
            hold on;
            scatter_obj = plot(x, yx, '*w', 'MarkerSize', 3);
            title_obj = title([]);
            hold off;

            
            for i = 1:length(z_pos)

                if d.CancelRequested
                    return;
                end
                
                d.Value = i / length(z_pos);
                d.Message = sprintf('Processing step %d of %d', i, length(z_pos));
                drawnow;

                % move stage
                app.Stage.MoveToAbsPosition(z_pos(i));
                % app.AutoExposureButtonPushed();
                app.AutoExposure_EllButtonPushed;
                pause(0.1);

                % take 5 frames and mean them
                for j = 1:5
                    frame_tmp(:,:,j) = app.cam.getFrame();
                    pause(0.1);
                end
                frame_original = mean(frame_tmp, 3);
                saved_frames(i,:,:) = frame_original;

                frame = frame_original;

                % get D4s and store in array
                % D4s(i) = app.cam.get_D4sigma_dymanic(frame);
                % D4s(i) = app.D4s_dymanic_wraper(frame);
                D4s(i) = app.cam.get_D4sigma(frame);

                % Update the plot data
                subplot(1,3,3);
                image_obj = imagesc(frame);
                colormap jet;
                hold on;
                scatter_obj = plot(D4s(i).xel, D4s(i).yel, '*w', 'MarkerSize', 3);
                title_obj = title("D4sX:" + string(D4s(i).D4sigmaX) +...
                                        " D4sY:" + string(D4s(i).D4sigmaY));
                xlim([min(D4s(i).xel)*0.9 max(D4s(i).xel)*1.1]);
                ylim([min(D4s(i).yel)*0.9 max(D4s(i).yel)*1.1]);
                axis square;
                hold off;
                drawnow;


                subplot(1, 3, 1);
                x = [x, z_pos(i)];
                yx = [yx, D4s(i).D4sigmaX];
                plot(x, yx, 'x', 'MarkerSize', 15, 'MarkerFaceColor', 'r'); % Update plot data
                xlim([min(x), max(x)+1e-2]); % Ensure x-axis covers all data points
                ylim([min(yx)*0.9, max(yx)*1.1]); % Adjust y-axis with some padding
                title("W2-X")
                drawnow;

                subplot(1, 3, 2);
                yy = [yy, D4s(i).D4sigmaY];
                plot(x, yy, 'x', 'MarkerSize', 15, 'MarkerFaceColor', 'r'); % Update plot data
                xlim([min(x), max(x)+1e-2]); % Ensure x-axis covers all data points
                ylim([min(yy)*0.9, max(yy)*1.1]); % Adjust y-axis with some padding
                title("W2-Y")
                drawnow;

            end

            % building data struct to save

            % app.last_M2_data.lambda = lambda;
            % app.last_M2_data.raw.D4s = D4s;
            app.last_M2_data.raw.z_pos = z_pos;
            % app.last_M2_data.raw.W2x = W2x;
            % app.last_M2_data.raw.W2y = W2y;
            % app.last_M2_data.raw.x_axsis = x_axsis;
            app.last_M2_data.raw.frames = saved_frames;


            % Start anlysis

            lambda = app.WavelengthEditField.Value * 1e-3; % [mm]

            % normalize around 0
            x_axsis = ( z_pos - z0 ) * 2; % 1mm of the stage is twice the size for the light
            W2x = ( [D4s.D4sigmaX]./2 .* app.cam.param.pxl_length * 1e-3 ) .^2; % [mm^2]
            W2y = ( [D4s.D4sigmaY]./2 .* app.cam.param.pxl_length * 1e-3 ) .^2; % [mm^2]


            % M2 X axis
            n_point_near_w0 = 0;
            [Xparams, x_new, W2x_fit,Xparams_range] = app.M2Fit(x_axsis, W2x,n_point_near_w0);
            M2x = sqrt( Xparams(2) * (pi * sqrt(Xparams(1)) / lambda).^2 );
            
            % M2x_Range
            for i=1:2
                for j=1:2
                    M2x_values(i,j) = sqrt( Xparams_range(i,2) .* (pi .* sqrt(Xparams_range(j,1)) ./ lambda).^2 );
                end
            end
            M2x_max = max(M2x_values(:));
            M2x_min = min(M2x_values(:));
            
            M2x_range = max( abs(M2x-M2x_min), abs(M2x-M2x_max) );

            figure("Name", "M2 X calculation measurment");
            plot(x_axsis,sqrt(W2x),'x','Color',[1 0 0],'DisplayName','data');
            hold on
            plot(x_new,sqrt(W2x_fit),'Color',[0 0 1],'DisplayName','fit');
            hold off;
            xlabel('Z [mm]');
            ylabel('W_x [mm]');
            title(['M2 - X = ' num2str(M2x) '+-' num2str(M2x_range)])

            % M2 Y axsis
            [Yparams, x_new, W2y_fit,Yparams_range] = app.M2Fit(x_axsis, W2y,n_point_near_w0);
            M2y = sqrt( Yparams(2) * (pi * sqrt(Yparams(1)) / lambda).^2 );

            % M2y_Range
            for i=1:2
                for j=1:2
                    M2y_values(i,j) = sqrt( Yparams_range(i,2) .* (pi .* sqrt(Yparams_range(j,1)) ./ lambda).^2 );
                end
            end
            M2y_max = max(M2y_values(:));
            M2y_min = min(M2y_values(:));
            M2y_range = max( abs(M2y-M2y_min), abs(M2y-M2y_max) );

            figure("Name", "M2 Y calculation measurment");
            plot(x_axsis,sqrt(W2y),'x','Color',[1 0 0],'DisplayName','data');
            hold on
            plot(x_new,sqrt(W2y_fit),'Color',[0 0 1],'DisplayName','fit');
            hold off;
            xlabel('Z [mm]');
            ylabel('W_y [mm]');
            title(['M2 - Y = ' num2str(M2y) '+-' num2str(M2y_range)])


        end

        % Button pushed function: runM2anlysisButton
        function runM2anlysisButtonPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"M2 Anlysis", 'Cancelable', 'on');            
            
            z_pos = app.last_M2_data.raw.z_pos;
            z0 = app.Z0EditField.Value;
            zr = app.ZrEditField.Value;
            d_zr = abs(zr - z0);
            % n_point_near_w0 = sum( abs((z_pos- z0)/d_zr ) < 1 );
            n_point_near_w0 = 3;


            % setting the plot
            figure;

            subplot(1,2,1)
            title('Waist');
            xlabel('Z [mm]');
            ylabel('D4\sigmaX');
            % Initialize an empty plot
            x = [];
            y= [];
            h = plot(x, y, 'x', 'MarkerSize', 15, 'MarkerFaceColor', 'r');

            subplot(1,2,2)
            image_obj = imagesc([]);
            colormap jet;
            hold on;
            scatter_obj = plot(x, y, '*w', 'MarkerSize', 3);
            title_obj = title([]);
            hold off;


            % mark the frames

            for i = 1: size(app.last_M2_data.raw.frames, 1)

                if d.CancelRequested
                    return;
                end
                
                d.Value = i / length(z_pos);
                d.Message = sprintf('Processing step %d of %d', i, length(z_pos));
                drawnow;

                frame = app.cleanFrame( app.last_M2_data.raw.frames(i,:,:) );

                % get D4s and store in array
                D4s(i) = app.cam.get_D4sigma(frame,30);

                % Update the plot data
                subplot(1,2,2);
                image_obj = imagesc(frame);
                colormap jet;
                hold on;
                scatter_obj = plot(D4s(i).xel, D4s(i).yel, '*w', 'MarkerSize', 3);
                title_obj = title("D4sX:" + string(D4s(i).D4sigmaX) +...
                                        " D4sY:" + string(D4s(i).D4sigmaY));
                xlim([min(D4s(i).xel)*0.9 max(D4s(i).xel)*1.1]);
                ylim([min(D4s(i).yel)*0.9 max(D4s(i).yel)*1.1]);
                axis square;
                hold off;
                drawnow;

                subplot(1, 2, 1);
                x = [x, z_pos(i)];
                y = [y, D4s(i).D4sigmaX];
                plot(x, y, 'x', 'MarkerSize', 15, 'MarkerFaceColor', 'r'); % Update plot data
                xlim([min(x), max(x)+1e-2]); % Ensure x-axis covers all data points
                ylim([min(y)*0.9, max(y)*1.1]); % Adjust y-axis with some padding
                drawnow;

            end


            % Start anlysis

            lambda = app.WavelengthEditField.Value * 1e-3; % [mm]

            % normalize around 0
            x_axsis = ( z_pos - z0 ) * 2; % 1mm of the stage is twice the size for the light
            W2x = ( [D4s.D4sigmaX]./2 .* app.cam.param.pxl_length * 1e-3 ) .^2; % [mm^2]
            W2y = ( [D4s.D4sigmaY]./2 .* app.cam.param.pxl_length * 1e-3 ) .^2; % [mm^2]


            % M2 X axis
            [Xparams, x_new, W2x_fit,Xparams_range] = app.M2Fit(x_axsis, W2x,n_point_near_w0);
            M2x = sqrt( Xparams(2) * (pi * sqrt(Xparams(1)) / lambda).^2 );
            
            % M2x_Range
            for i=1:2
                for j=1:2
                    M2x_values(i,j) = sqrt( Xparams_range(i,2) .* (pi .* sqrt(Xparams_range(j,1)) ./ lambda).^2 );
                end
            end
            M2x_max = max(M2x_values(:));
            M2x_min = min(M2x_values(:));
            
            M2x_range = max( abs(M2x-M2x_min), abs(M2x-M2x_max) );

            figure("Name", "M2 X calculation measurment");
            plot(x_axsis,sqrt(W2x),'x','Color',[1 0 0],'DisplayName','data');
            hold on
            plot(x_new,sqrt(W2x_fit),'Color',[0 0 1],'DisplayName','fit');
            hold off;
            xlabel('Z [mm]');
            ylabel('W_x [mm]');
            title(['M2 - X = ' num2str(M2x) '+-' num2str(M2x_range)])

            % M2 Y axsis
            [Yparams, x_new, W2y_fit,Yparams_range] = app.M2Fit(x_axsis, W2y,n_point_near_w0);
            M2y = sqrt( Yparams(2) * (pi * sqrt(Yparams(1)) / lambda).^2 );

            % M2y_Range
            for i=1:2
                for j=1:2
                    M2y_values(i,j) = sqrt( Yparams_range(i,2) .* (pi .* sqrt(Yparams_range(j,1)) ./ lambda).^2 );
                end
            end
            M2y_max = max(M2y_values(:));
            M2y_min = min(M2y_values(:));
            M2y_range = max( abs(M2y-M2y_min), abs(M2y-M2y_max) );

            figure("Name", "M2 Y calculation measurment");
            plot(x_axsis,sqrt(W2y),'x','Color',[1 0 0],'DisplayName','data');
            hold on
            plot(x_new,sqrt(W2y_fit),'Color',[0 0 1],'DisplayName','fit');
            hold off;
            xlabel('Z [mm]');
            ylabel('W_y [mm]');
            title(['M2 - Y = ' num2str(M2y) '+-' num2str(M2y_range)])


            % building data struct to save
            app.last_M2_data.lambda = lambda;
            app.last_M2_data.raw.D4s = D4s;
            % app.last_M2_data.raw.z_pos = z_pos;
            app.last_M2_data.raw.W2x = W2x; 
            app.last_M2_data.raw.W2y = W2y; 
            app.last_M2_data.raw.x_axsis = x_axsis;
            % app.last_M2_data.raw.frames = saved_frames;

            app.last_M2_data.fit.x_axsis = x_new;
            app.last_M2_data.fit.W2x = W2x_fit;
            app.last_M2_data.fit.W2y = W2y_fit;
            app.last_M2_data.fit.M2x = M2x;
            app.last_M2_data.fit.M2y = M2y;
            app.last_M2_data.fit.M2x_range = M2x_range;
            app.last_M2_data.fit.M2y_range = M2y_range;


            close(d)
            app.UpdateLog('DOME M2 calculation', [0 1 0]);


            % save data
            app.saveM2ButtonPushed()
        end

        % Value changed function: TriggerModeSwitch
        function TriggerModeSwitchValueChanged(app, event)
            Trigger_on = app.TriggerModeSwitch.Value;
            
            if strcmp(Trigger_on,'On') % enable Trigger mode
                app.cam.src.TriggerDelay = app.TriggerDelayusEditField.Value;
                app.cam.src.TriggerMode = "On";
                app.cam.src.TriggerSource = "Line3";
                executeCommand(app.cam.src,"TriggerSoftware");


            else % disable Trigger mode
                app.cam.src.TriggerMode = "Off";
            end


        end

        % Value changed function: TriggerDelayusEditField
        function TriggerDelayusEditFieldValueChanged(app, event)
            value = app.TriggerDelayusEditField.Value;
            app.cam.src.TriggerDelay = value;
        end

        % Button pushed function: startrecordButton
        function startrecordButtonPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Recording in Progress", 'Cancelable', 'on');

            
            x_cent = app.xcenterEditField.Value;
            y_cent = app.ycenterEditField.Value;
            window_size = app.windowEditField.Value;

            % Get original resolution
            originalROI = get(app.cam.device, 'ROIPosition');

            x_low = x_cent-window_size/2;
            x_high = x_cent+window_size/2;
            y_low = y_cent-window_size/2;
            y_high = y_cent+window_size/2;

            x_range = floor(x_low : x_high);
            y_range = floor(y_low : y_high);

            % change camera ROI
            % Stop the device before changing ROI
            stop(app.cam.device);
            % newROI = [x_low y_low x_high y_high];
            newROI = [x_low y_low window_size  window_size];
            set(app.cam.device, 'ROIPosition', newROI);
            start(app.cam.device);


            frames_rec = zeros(window_size, window_size, app.numberofframesEditField.Value);
            
            app.UpdateLog('Recording Start');

            for ii=1:app.numberofframesEditField.Value
                if d.CancelRequested
                    break;
                end
                frames_rec(:,:,ii) = app.cam.getFrame();
            end

            % Stop the device before returning to full frame
            stop(app.cam.device);

            % Return to full frame
            set(app.cam.device, 'ROIPosition', originalROI);

            % Restart the device
            start(app.cam.device);

            app.UpdateLog('ROI returned to original');


            % Open a dialog box for the user to choose where to save the file
            [file, path] = uiputfile('*.mat', 'Save Data As');

            if file ~= 0
                % User selected a file, so save the data
                fullPath = fullfile(path, file);
                save(fullPath, 'frames_rec', '-v7.3');

                % Optionally, display a message to confirm the save
                msgbox('Data saved successfully!', 'Save Complete');
            end

        end

        % Button pushed function: measureintegralButton
        function measureintegralButtonPushed(app, event)
            
            frame = app.cleanFrame(app.cam.getFrame);
            % frame = app.cam.getFrame;
            integral = sum(frame,"all");
            app.MeasureIntegralEditField.Value = integral;


        end

        % Button pushed function: closeSpectrometer
        function closeSpectrometerButtonPushed(app, event)

            % Clean up
            app.spectrometer.disconnect();

            % app.SpectrometerTab.Enable = "off";
            app.closeSpectrometer.Enable = "off";
            app.ConnectSpectrometer.Enable = 'on';
            app.ConnectSpectrometer.Text = 'Connect';
            app.ConnectSpectrometer.BackgroundColor = [1 0 0]; % red
            app.UpdateLog('Spectrometer Disconnected' ,[0 1 0]);
            
            app.spectrometer = [];


        end

        % Button pushed function: ConnectSpectrometer
        function ConnectSpectrometerButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Initializing connection to the Spectrometer");

            
            % Create an instance of the driver
            try
                app.spectrometer = SpectrometerDriver();
            catch ME
                err_msg = ['Error creating driver: %s\n', ME.message];
                app.UpdateLog(err_msg, [1 0 0]);
            end

            % Connect to the device
            try
                if ~app.spectrometer.connect()
                    app.UpdateLog('Failed to connect to spectrometer', [1 0 0]);
                else
                    % app.SpectrometerTab.Enable = "on";
                    app.UpdateLog('Spectrometer Connected', [0 1 0]);
                    app.ConnectSpectrometer.Text = 'Connected';
                    app.ConnectSpectrometer.Enable = "off";
                    app.ConnectSpectrometer.BackgroundColor = [0 1 0];

                    app.closeSpectrometer.Enable = 'on';

                end
            catch ME
                err_msg = ['Connection error: %s\n', ME.message];
                app.UpdateLog(err_msg, [1 0 0]);
            end


        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.spectrometer.exposureTime = app.ExposuretimemsEditField.Value;
            app.spectrometer.averaging = app.AveragingEditField.Value;
            app.spectrometer.autoExposure = false;
            
            app.spectrometer.applySettings();
            app.UpdateLog('Setting applied', [0 1 0]);

        end

        % Value changed function: TriggerSwitch
        function TriggerSwitchValueChanged(app, event)
             Trigger_on = app.TriggerSwitch.Value;
            
            if strcmp(Trigger_on,'On') % enable Trigger mode
                app.spectrometer.setTriggerMode(1);  % 1 = external trigger
                app.UpdateLog('Trigger on', [0 1 0]);
            else
                app.spectrometer.setTriggerMode(0);  %  0 = Internal trigger
                app.UpdateLog('Trigger off', [0 1 0]);

            end
        end

        % Button pushed function: getonceButton
        function getonceButtonPushed(app, event)
            % Acquire a spectrum
            [spectrum, ~] = app.spectrometer.acquireSpectrum();

            plot(app.SpectrumFigure, app.spectrometer.wavelengths, spectrum);


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Create figure and plot objects
            fig = figure('Name', 'Real-time Spectrum', 'NumberTitle', 'off');
            ax = axes(fig);
            h = plot(ax, app.spectrometer.wavelengths, zeros(size(app.spectrometer.wavelengths)));
            grid(ax, 'on');
            xlabel(ax, 'Wavelength [nm]');
            ylabel(ax, 'Intensity');
            title(ax, sprintf('Real-time Spectrum - %s', app.spectrometer.deviceName));

            % Optional: Set axis limits based on wavelength range and expected intensity
            xlim(ax, [min(app.spectrometer.wavelengths) max(app.spectrometer.wavelengths)]);
            ylim(ax, [0 65535]);  % Adjust based on your spectrometer's bit depth

            % Add a stop button
            btnStop = uicontrol('Style', 'pushbutton', 'String', 'Stop',...
                'Position', [20 20 50 20],...
                'Callback', 'delete(gcbf)');

            % Continuous acquisition loop
            while ishandle(fig)  % Run until figure is closed
                % Acquire new spectrum
                [spectrum, metadata] = app.spectrometer.acquireSpectrum();

                % Update plot
                set(h, 'YData', spectrum);

                % Update title with load level
                title(ax, sprintf('Real-time Spectrum - Load Level: %.1f%%', metadata.loadLevel));

                % Force drawing update
                drawnow;
            end

        end

        % Button pushed function: getdarkButton
        function getdarkButtonPushed(app, event)
            app.spectrometer.acquireDarkSpectrum();
            app.getdarkButton.BackgroundColor = [1 1 0];


        end

        % Button pushed function: recordwithspectrumButton
        function recordwithspectrumButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Recording in Progress", 'Cancelable', 'on');

            
            x_cent = app.xcenterEditField.Value;
            y_cent = app.ycenterEditField.Value;
            window_size = app.windowEditField.Value;

            % Get original resolution
            originalROI = get(app.cam.device, 'ROIPosition');

            x_low = x_cent-window_size/2;
            x_high = x_cent+window_size/2;
            y_low = y_cent-window_size/2;
            y_high = y_cent+window_size/2;

            x_range = floor(x_cent-window_size/2 : x_cent+window_size/2);
            y_range = floor(y_cent-window_size/2 : y_cent+window_size/2);

            % change camera ROI
            % Stop the device before changing ROI
            stop(app.cam.device);
            newROI = [x_low y_low window_size  window_size];
            set(app.cam.device, 'ROIPosition', newROI);
            start(app.cam.device);

            app.UpdateLog('Camera ROI changed');

            frames_rec = zeros(window_size, window_size,app.numberofframesEditField.Value);
            spectrum_rec = zeros(app.numberofframesEditField.Value, app.spectrometer.pixelCount);
            spectrum_wl = app.spectrometer.wavelengths;
            
            app.UpdateLog('Recording Start');

            for ii=1:app.numberofframesEditField.Value

                if d.CancelRequested
                    break;
                end

                frames_rec(:,:,ii) = app.cam.getFrame();
                [spectrum_rec(ii,:), ~] = app.spectrometer.acquireSpectrum();

            end

            % Stop the device before returning to full frame
            stop(app.cam.device);

            % Return to full frame
            set(app.cam.device, 'ROIPosition', originalROI);

            % Restart the device
            start(app.cam.device);

            app.UpdateLog('ROI returned to original');


            % Open a dialog box for the user to choose where to save the file
            [file, path] = uiputfile('*.mat', 'Save Data As');

            if file ~= 0
                % User selected a file, so save the data
                fullPath = fullfile(path, file);
                save(fullPath, 'frames_rec', 'spectrum_rec', 'spectrum_wl', '-v7.3');

                % Optionally, display a message to confirm the save
                msgbox('Data saved successfully!', 'Save Complete');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.ThorlabMotorPanel = uipanel(app.ConfigurationTab);
            app.ThorlabMotorPanel.Title = 'Thorlab Motor ';
            app.LockStageButton = uibutton(app.ThorlabMotorPanel, 'push');
            app.LockStageButton.ButtonPushedFcn = createCallbackFcn(app, @LockStageButtonPushed, true);
            app.LockStageButton.Enable = 'off';
            app.LockStageButton.Text = 'Lock';
            app.CloseStageButton = uibutton(app.ThorlabMotorPanel, 'push');
            app.CloseStageButton.ButtonPushedFcn = createCallbackFcn(app, @CloseStageButtonPushed, true);
            app.CloseStageButton.Enable = 'off';
            app.CloseStageButton.Text = 'Close';
            app.ConnectStageButton = uibutton(app.ThorlabMotorPanel, 'push');
            app.ConnectStageButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectStageButtonPushed, true);
            app.ConnectStageButton.Text = 'Connect';
            app.MotorAddressDropDownLabel = uilabel(app.ThorlabMotorPanel);
            app.MotorAddressDropDownLabel.Text = 'Motor Address';
            app.MotorAddressDropDown = uidropdown(app.ThorlabMotorPanel);
            app.MotorAddressDropDown.Items = {''};
            app.MotorAddressDropDown.Enable = 'off';
            app.MotorAddressDropDown.Value = '';
            app.CameraPanel = uipanel(app.ConfigurationTab);
            app.CameraPanel.Title = 'Camera';
            app.ConnectCameraButton = uibutton(app.CameraPanel, 'push');
            app.ConnectCameraButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectCameraButtonPushed, true);
            app.ConnectCameraButton.Text = 'Connect';
            app.closeCameraButton = uibutton(app.CameraPanel, 'push');
            app.closeCameraButton.ButtonPushedFcn = createCallbackFcn(app, @closeCameraButtonPushed, true);
            app.closeCameraButton.Enable = 'off';
            app.closeCameraButton.Text = 'close';
            app.ElliptecPanel = uipanel(app.ConfigurationTab);
            app.ElliptecPanel.Title = 'Elliptec';
            app.ConnectEllButton = uibutton(app.ElliptecPanel, 'push');
            app.ConnectEllButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectEllButtonPushed, true);
            app.ConnectEllButton.Text = 'Connect';
            app.closeEllButton = uibutton(app.ElliptecPanel, 'push');
            app.closeEllButton.ButtonPushedFcn = createCallbackFcn(app, @closeEllButtonPushed, true);
            app.closeEllButton.Enable = 'off';
            app.closeEllButton.Text = 'close';
            app.COMEditFieldLabel = uilabel(app.ElliptecPanel);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.ElliptecPanel, 'text');
            app.COMEditField.Value = 'COM7';
            app.addressEditFieldLabel = uilabel(app.ElliptecPanel);
            app.addressEditFieldLabel.Text = 'address';
            app.addressEditField = uieditfield(app.ElliptecPanel, 'text');
            app.addressEditField.Value = '0';
            app.AvenirSpectrometerPanel = uipanel(app.ConfigurationTab);
            app.AvenirSpectrometerPanel.Title = 'Avenir Spectrometer';
            app.ConnectSpectrometer = uibutton(app.AvenirSpectrometerPanel, 'push');
            app.ConnectSpectrometer.ButtonPushedFcn = createCallbackFcn(app, @ConnectSpectrometerButtonPushed, true);
            app.ConnectSpectrometer.Text = 'Connect';
            app.closeSpectrometer = uibutton(app.AvenirSpectrometerPanel, 'push');
            app.closeSpectrometer.ButtonPushedFcn = createCallbackFcn(app, @closeSpectrometerButtonPushed, true);
            app.closeSpectrometer.Enable = 'off';
            app.closeSpectrometer.Text = 'close';
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.CameraSettingPanel = uipanel(app.CameraTab);
            app.CameraSettingPanel.Enable = 'off';
            app.CameraSettingPanel.Title = 'Camera Setting';
            app.ZoomEditField = uieditfield(app.CameraSettingPanel, 'numeric');
            app.ZoomEditField.ValueChangedFcn = createCallbackFcn(app, @ZoomEditFieldValueChanged, true);
            app.usLabel = uilabel(app.CameraSettingPanel);
            app.usLabel.Text = ' [us]';
            app.ExposureEditField = uieditfield(app.CameraSettingPanel, 'numeric');
            app.ExposureEditField.Limits = [29 1000000];
            app.ExposureEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureEditFieldValueChanged, true);
            app.ExposureEditField.Value = 29;
            app.ColormapDropDown = uidropdown(app.CameraSettingPanel);
            app.ColormapDropDown.Items = {'jet', 'gray', 'hsv', 'hot', 'parula'};
            app.ColormapDropDown.Editable = 'on';
            app.ColormapDropDown.Value = 'jet';
            app.ColormapLabel = uilabel(app.CameraSettingPanel);
            app.ColormapLabel.Text = 'Colormap';
            app.D4sCheckBox = uicheckbox(app.CameraSettingPanel);
            app.D4sCheckBox.ValueChangedFcn = createCallbackFcn(app, @D4sCheckBoxValueChanged, true);
            app.D4sCheckBox.Text = 'D4s';
            app.LiveButton = uibutton(app.CameraSettingPanel, 'push');
            app.LiveButton.ButtonPushedFcn = createCallbackFcn(app, @LiveButtonPushed, true);
            app.LiveButton.Text = 'Live';
            app.ZoomButton = uibutton(app.CameraSettingPanel, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.ExposuretimeButton = uibutton(app.CameraSettingPanel, 'push');
            app.ExposuretimeButton.ButtonPushedFcn = createCallbackFcn(app, @ExposuretimeButtonPushed, true);
            app.ExposuretimeButton.Text = 'Exposure time';
            app.AutoExposureButton = uibutton(app.CameraSettingPanel, 'push');
            app.AutoExposureButton.ButtonPushedFcn = createCallbackFcn(app, @AutoExposureButtonPushed, true);
            app.AutoExposureButton.Text = 'Auto';
            app.saveframeButton = uibutton(app.CameraSettingPanel, 'push');
            app.saveframeButton.ButtonPushedFcn = createCallbackFcn(app, @saveframeButtonPushed, true);
            app.saveframeButton.Text = 'save frame';
            app.thresholdEditFieldLabel = uilabel(app.CameraSettingPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.CameraSettingPanel, 'numeric');
            app.thresholdEditField.Value = 100;
            app.AutoExposure_EllButton = uibutton(app.CameraSettingPanel, 'push');
            app.AutoExposure_EllButton.ButtonPushedFcn = createCallbackFcn(app, @AutoExposure_EllButtonPushed, true);
            app.AutoExposure_EllButton.Text = 'Auto+ELL';
            app.D4sdynamicCheckBox = uicheckbox(app.CameraSettingPanel);
            app.D4sdynamicCheckBox.ValueChangedFcn = createCallbackFcn(app, @D4sdynamicCheckBoxValueChanged, true);
            app.D4sdynamicCheckBox.Text = 'D4s dynamic';
            app.logCheckBox = uicheckbox(app.CameraSettingPanel);
            app.logCheckBox.Text = 'log';
            app.manualD4sButton = uibutton(app.CameraSettingPanel, 'push');
            app.manualD4sButton.ButtonPushedFcn = createCallbackFcn(app, @manualD4sButtonPushed, true);
            app.manualD4sButton.Text = 'manual D4s';
            app.TriggerModeSwitchLabel = uilabel(app.CameraSettingPanel);
            app.TriggerModeSwitchLabel.Text = 'Trigger Mode';
            app.TriggerModeSwitch = uiswitch(app.CameraSettingPanel, 'slider');
            app.TriggerModeSwitch.ValueChangedFcn = createCallbackFcn(app, @TriggerModeSwitchValueChanged, true);
            app.TriggerDelayusEditFieldLabel = uilabel(app.CameraSettingPanel);
            app.TriggerDelayusEditFieldLabel.Text = 'Trigger Delay [us]';
            app.TriggerDelayusEditField = uieditfield(app.CameraSettingPanel, 'numeric');
            app.TriggerDelayusEditField.ValueChangedFcn = createCallbackFcn(app, @TriggerDelayusEditFieldValueChanged, true);
            app.numberofframesEditFieldLabel = uilabel(app.CameraSettingPanel);
            app.numberofframesEditFieldLabel.Text = 'number of frames';
            app.numberofframesEditField = uieditfield(app.CameraSettingPanel, 'numeric');
            app.startrecordButton = uibutton(app.CameraSettingPanel, 'push');
            app.startrecordButton.ButtonPushedFcn = createCallbackFcn(app, @startrecordButtonPushed, true);
            app.startrecordButton.Text = 'start record';
            app.xcenterEditFieldLabel = uilabel(app.CameraSettingPanel);
            app.xcenterEditFieldLabel.Text = 'x center';
            app.xcenterEditField = uieditfield(app.CameraSettingPanel, 'numeric');
            app.xcenterEditField.Value = 500;
            app.ycenterEditFieldLabel = uilabel(app.CameraSettingPanel);
            app.ycenterEditFieldLabel.Text = 'y center';
            app.ycenterEditField = uieditfield(app.CameraSettingPanel, 'numeric');
            app.ycenterEditField.Value = 500;
            app.windowEditFieldLabel = uilabel(app.CameraSettingPanel);
            app.windowEditFieldLabel.Text = 'window';
            app.windowEditField = uieditfield(app.CameraSettingPanel, 'numeric');
            app.windowEditField.Value = 150;
            app.measureintegralButton = uibutton(app.CameraSettingPanel, 'push');
            app.measureintegralButton.ButtonPushedFcn = createCallbackFcn(app, @measureintegralButtonPushed, true);
            app.measureintegralButton.Text = 'measure integral';
            app.MeasureIntegralEditField = uieditfield(app.CameraSettingPanel, 'numeric');
            app.MeasureIntegralEditField.Editable = 'off';
            app.recordwithspectrumButton = uibutton(app.CameraSettingPanel, 'push');
            app.recordwithspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @recordwithspectrumButtonPushed, true);
            app.recordwithspectrumButton.Text = 'record with spectrum';
            app.SpectrometerTab = uitab(app.TabGroup);
            app.SpectrometerTab.Title = 'Spectrometer';
            app.SpectrumFigure = uiaxes(app.SpectrometerTab);
            title(app.SpectrumFigure, 'Spectrum')
            xlabel(app.SpectrumFigure, 'Wavelength [nm]')
            ylabel(app.SpectrumFigure, 'Intensity')
            zlabel(app.SpectrumFigure, 'Z')
            app.SettingPanel = uipanel(app.SpectrometerTab);
            app.SettingPanel.Title = 'Setting';
            app.ExposuretimemsEditFieldLabel = uilabel(app.SettingPanel);
            app.ExposuretimemsEditFieldLabel.Text = 'Exposure time [ms]';
            app.ExposuretimemsEditField = uieditfield(app.SettingPanel, 'numeric');
            app.ExposuretimemsEditField.Value = 0.02;
            app.AveragingEditFieldLabel = uilabel(app.SettingPanel);
            app.AveragingEditFieldLabel.Text = 'Averaging';
            app.AveragingEditField = uieditfield(app.SettingPanel, 'numeric');
            app.AveragingEditField.Value = 1;
            app.TriggerSwitchLabel = uilabel(app.SettingPanel);
            app.TriggerSwitchLabel.Text = 'Trigger';
            app.TriggerSwitch = uiswitch(app.SettingPanel, 'slider');
            app.TriggerSwitch.ValueChangedFcn = createCallbackFcn(app, @TriggerSwitchValueChanged, true);
            app.ApplyButton = uibutton(app.SettingPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.getonceButton = uibutton(app.SpectrometerTab, 'push');
            app.getonceButton.ButtonPushedFcn = createCallbackFcn(app, @getonceButtonPushed, true);
            app.getonceButton.Text = 'get once';
            app.RunButton = uibutton(app.SpectrometerTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.getdarkButton = uibutton(app.SpectrometerTab, 'push');
            app.getdarkButton.ButtonPushedFcn = createCallbackFcn(app, @getdarkButtonPushed, true);
            app.getdarkButton.Text = 'get dark ';
            app.E = uitab(app.TabGroup);
            app.E.Title = 'Elliptec';
            app.EllipteccontrolPanel = uipanel(app.E);
            app.EllipteccontrolPanel.Title = 'Elliptec control';
            app.ELLHomeButton = uibutton(app.EllipteccontrolPanel, 'push');
            app.ELLHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ELLHomeButtonPushed, true);
            app.ELLHomeButton.Text = 'Home';
            app.filter1Button = uibutton(app.EllipteccontrolPanel, 'push');
            app.filter1Button.ButtonPushedFcn = createCallbackFcn(app, @filter1ButtonPushed, true);
            app.filter1Button.Text = 'filter 1';
            app.filter2Button = uibutton(app.EllipteccontrolPanel, 'push');
            app.filter2Button.ButtonPushedFcn = createCallbackFcn(app, @filter2ButtonPushed, true);
            app.filter2Button.Text = 'filter 2';
            app.filter3Button = uibutton(app.EllipteccontrolPanel, 'push');
            app.filter3Button.ButtonPushedFcn = createCallbackFcn(app, @filter3ButtonPushed, true);
            app.filter3Button.Text = 'filter 3';
            app.filter4Button = uibutton(app.EllipteccontrolPanel, 'push');
            app.filter4Button.ButtonPushedFcn = createCallbackFcn(app, @filter4ButtonPushed, true);
            app.filter4Button.Enable = 'off';
            app.filter4Button.Text = 'filter 4';
            app.numberoffiltersEditFieldLabel = uilabel(app.EllipteccontrolPanel);
            app.numberoffiltersEditFieldLabel.Text = 'number of filters';
            app.numberoffiltersEditField = uieditfield(app.EllipteccontrolPanel, 'numeric');
            app.numberoffiltersEditField.Limits = [0 4];
            app.numberoffiltersEditField.ValueChangedFcn = createCallbackFcn(app, @numberoffiltersEditFieldValueChanged, true);
            app.numberoffiltersEditField.Value = 3;
            app.getposButton = uibutton(app.EllipteccontrolPanel, 'push');
            app.getposButton.ButtonPushedFcn = createCallbackFcn(app, @getposButtonPushed, true);
            app.getposButton.Text = 'get pos';
            app.ELLPosEditField = uieditfield(app.EllipteccontrolPanel, 'numeric');
            app.M2Tab = uitab(app.TabGroup);
            app.M2Tab.Title = 'M2';
            app.ThorlabStagePanel = uipanel(app.M2Tab);
            app.ThorlabStagePanel.Enable = 'off';
            app.ThorlabStagePanel.Title = 'Thorlab Stage';
            app.UpButton = uibutton(app.ThorlabStagePanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.ThorlabStagePanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.JogsizeEditFieldLabel = uilabel(app.ThorlabStagePanel);
            app.JogsizeEditFieldLabel.Text = 'Jog size';
            app.JogsizeEditField = uieditfield(app.ThorlabStagePanel, 'numeric');
            app.JogsizeEditField.Value = 10;
            app.MovetoPositionEditField = uieditfield(app.ThorlabStagePanel, 'numeric');
            app.MovetoPositionEditField.ValueChangedFcn = createCallbackFcn(app, @MovetoPositionEditFieldValueChanged, true);
            app.MovetoabsoluteButton = uibutton(app.ThorlabStagePanel, 'push');
            app.MovetoabsoluteButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoabsoluteButtonPushed, true);
            app.MovetoabsoluteButton.Text = 'Move to absolute';
            app.PositionEditField_2Label = uilabel(app.ThorlabStagePanel);
            app.PositionEditField_2Label.Text = 'Position';
            app.PositionEditField = uieditfield(app.ThorlabStagePanel, 'text');
            app.PositionEditField.Editable = 'off';
            app.InitialStepmmEditFieldLabel = uilabel(app.M2Tab);
            app.InitialStepmmEditFieldLabel.Text = 'Initial Step [mm]';
            app.InitialStepEditField = uieditfield(app.M2Tab, 'numeric');
            app.InitialStepEditField.Value = 20;
            app.FindWaistLocationButton = uibutton(app.M2Tab, 'push');
            app.FindWaistLocationButton.ButtonPushedFcn = createCallbackFcn(app, @FindWaistLocationButtonPushed, true);
            app.FindWaistLocationButton.Enable = 'off';
            app.FindWaistLocationButton.Text = 'Find Waist Location';
            app.W0EditFieldLabel = uilabel(app.M2Tab);
            app.W0EditFieldLabel.Text = 'W0';
            app.W0EditField = uieditfield(app.M2Tab, 'numeric');
            app.Z0EditFieldLabel = uilabel(app.M2Tab);
            app.Z0EditFieldLabel.Text = 'Z0';
            app.Z0EditField = uieditfield(app.M2Tab, 'numeric');
            app.MinimalStepmmEditFieldLabel = uilabel(app.M2Tab);
            app.MinimalStepmmEditFieldLabel.Text = 'Minimal Step [mm]';
            app.MinimalStepEditField = uieditfield(app.M2Tab, 'numeric');
            app.MinimalStepEditField.Value = 1;
            app.SteplengthEditFieldLabel = uilabel(app.M2Tab);
            app.SteplengthEditFieldLabel.Text = 'Step length';
            app.SteplengthEditField = uieditfield(app.M2Tab, 'numeric');
            app.SteplengthEditField.Value = 4;
            app.FindRayleighLengthButton = uibutton(app.M2Tab, 'push');
            app.FindRayleighLengthButton.ButtonPushedFcn = createCallbackFcn(app, @FindRayleighLengthButtonPushed, true);
            app.FindRayleighLengthButton.Enable = 'off';
            app.FindRayleighLengthButton.Text = 'Find Rayleigh Length';
            app.ZrEditFieldLabel = uilabel(app.M2Tab);
            app.ZrEditFieldLabel.Text = 'Zr';
            app.ZrEditField = uieditfield(app.M2Tab, 'numeric');
            app.getM2framesManualButton = uibutton(app.M2Tab, 'push');
            app.getM2framesManualButton.ButtonPushedFcn = createCallbackFcn(app, @getM2framesManualButtonPushed, true);
            app.getM2framesManualButton.Text = 'get M2 frames - Manual';
            app.M2EditFieldLabel = uilabel(app.M2Tab);
            app.M2EditFieldLabel.Enable = 'off';
            app.M2EditFieldLabel.Text = 'M2';
            app.M2EditField = uieditfield(app.M2Tab, 'numeric');
            app.M2EditField.Editable = 'off';
            app.M2EditField.Enable = 'off';
            app.StartPositionmmLabel = uilabel(app.M2Tab);
            app.StartPositionmmLabel.Text = 'Start Position [mm]';
            app.StartPositionEditField = uieditfield(app.M2Tab, 'numeric');
            app.StartPositionEditField.Value = 80;
            app.WavelengthumLabel = uilabel(app.M2Tab);
            app.WavelengthumLabel.Text = 'Wavelength [um]';
            app.WavelengthEditField = uieditfield(app.M2Tab, 'numeric');
            app.WavelengthEditField.Value = 1.064;
            app.saveM2Button = uibutton(app.M2Tab, 'push');
            app.saveM2Button.ButtonPushedFcn = createCallbackFcn(app, @saveM2ButtonPushed, true);
            app.saveM2Button.Text = 'save';
            app.getM2framesButton = uibutton(app.M2Tab, 'push');
            app.getM2framesButton.ButtonPushedFcn = createCallbackFcn(app, @getM2framesButtonPushed, true);
            app.getM2framesButton.Text = 'get M2 frames';
            app.runM2anlysisButton = uibutton(app.M2Tab, 'push');
            app.runM2anlysisButton.ButtonPushedFcn = createCallbackFcn(app, @runM2anlysisButtonPushed, true);
            app.runM2anlysisButton.Text = 'run M2 anlysis';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogEdit = uitextarea(app.UIFigure);
            app.LogEdit.Tag = 'LogBox';
            app.LogEdit.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CameraApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end