classdef CameraApp_PS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ELLLamp                        matlab.ui.control.Lamp
        LampCam                        matlab.ui.control.Lamp
        TabGroup                       matlab.ui.container.TabGroup
        CameraTab                      matlab.ui.container.Tab
        CameraPanel                    matlab.ui.container.Panel
        StartCam                       matlab.ui.control.Button
        CircCheckBox                   matlab.ui.control.CheckBox
        NumberofframestotakeEditField  matlab.ui.control.NumericEditField
        NumberofframestotakeEditFieldLabel  matlab.ui.control.Label
        GainEditField                  matlab.ui.control.NumericEditField
        GainEditFieldLabel             matlab.ui.control.Label
        FFTCheckBox                    matlab.ui.control.CheckBox
        DropDown                       matlab.ui.control.DropDown
        FWHMCamera                     matlab.ui.control.Button
        FilepathEditField              matlab.ui.control.EditField
        FilepathEditFieldLabel         matlab.ui.control.Label
        D4sCheckBox                    matlab.ui.control.CheckBox
        ZoomEditField                  matlab.ui.control.NumericEditField
        ZoomEditFieldLabel             matlab.ui.control.Label
        PreviewCamera_2                matlab.ui.control.Button
        PreviewCamera                  matlab.ui.control.Button
        AutoExposure                   matlab.ui.control.Button
        ExposureEditField              matlab.ui.control.NumericEditField
        ExposureEditFieldLabel         matlab.ui.control.Label
        ExitCam                        matlab.ui.control.Button
        ElliptecTab                    matlab.ui.container.Tab
        GoTommEditField                matlab.ui.control.NumericEditField
        GoTommEditFieldLabel           matlab.ui.control.Label
        AdressEditField                matlab.ui.control.EditField
        AdressEditFieldLabel           matlab.ui.control.Label
        PositionmmEditField            matlab.ui.control.NumericEditField
        PositionmmEditFieldLabel       matlab.ui.control.Label
        HomeELL                        matlab.ui.control.Button
        ELLstatus                      matlab.ui.control.Label
        ComPortEditField               matlab.ui.control.EditField
        ComPortEditFieldLabel          matlab.ui.control.Label
        CloseELL                       matlab.ui.control.Button
        OpenELL                        matlab.ui.control.Button
        M2Tab                          matlab.ui.container.Tab
        RayleighrangemmEditField       matlab.ui.control.NumericEditField
        RayleighrangemmEditFieldLabel  matlab.ui.control.Label
        CamerapixelsizeumEditField     matlab.ui.control.NumericEditField
        CamerapixelsizeumEditFieldLabel  matlab.ui.control.Label
        RunScanButton                  matlab.ui.control.Button
        RunCalibrationButton           matlab.ui.control.Button
        WavelengthumEditField          matlab.ui.control.NumericEditField
        WavelengthumEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        cam = []; % camera pointer
        src = [];
        cam_param = [];
        PrevFigH = [];
        ScanFlag = 1;
        daqObj = [];
        ROI = [];
        ELL = [];


    end
    
    methods (Access = private)
        
        function frame = get_cam_frame(app)
            if ~isempty(app.cam)
                
                frame =  double( getsnapshot(app.cam) );
%                 wait(app.cam)
                % rotate the frame 90 deg
%                 frame = frame';
            else 
                frame = [];
            end
        end
        
        function results = im2mfd(app, frame)
            
            thr = max(max(frame(1:5, 1:5)))*5;
            thr = 100;
            frame = frame-thr;
            
            frame(frame<0) = 0;
%             frame =  imgaussfilt(frame,1);
            im = double( frame );
            [x, y] = meshgrid(1:size(frame,2), 1:size(frame,1));
            xCent = sum(sum(x.*im))/sum(sum(im));
            yCent = sum(sum(y.*im))/sum(sum(im));
            D4sigmaX = 4*sqrt( sum(sum((x-xCent).^2.*im))/sum(sum(im)) );
            D4sigmaY = 4*sqrt( sum(sum((y-yCent).^2.*im))/sum(sum(im)) );
            D4sigmaXY = sum(sum((x-xCent).*(y-yCent).*im))/sum(sum(im));

            results.xCent = xCent;
            results.yCent = yCent;
            results.D4sigmaX = D4sigmaX;
            results.D4sigmaY = D4sigmaY;
            results.D4sigmaXY = D4sigmaY;
            theta = 0.5*atan( D4sigmaXY/((0.25*D4sigmaX)^2 -(0.25*D4sigmaY)^2) );
            t = linspace(0, 2*pi, 40);
            xelip = D4sigmaX/2*cos(t);
            yelip = D4sigmaY/2*sin(t);
            R  = [cos(theta) -sin(theta); sin(theta)  cos(theta)];
            CorR = R*[xelip ; yelip];
            results.xel = xCent+CorR(1,:);
            results.yel = yCent+CorR(2,:);
        end
        
        
        function results = MakeGaussFit2image(app)
            frame = get_cam_frame(app);
            im = double( frame );
            im = imgaussfilt(im,2);
            im = im-1;
            im(im<0) = 0;
            [x, y] = meshgrid(1:size(im,2), 1:size(im,1));
            
            xCent = sum(sum(x.*im))/sum(sum(im));
            yCent = sum(sum(y.*im))/sum(sum(im));
            
            %% fix x
            xdata = im(:,round(xCent))/max(im(:,round(xCent)));
            xx = y(:,1);
            [xData, yData] = prepareCurveData( xx, xdata );
            % Set up fittype and options.
            ft = fittype( 'gauss1' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Display = 'Off';
            opts.Lower = [0 -Inf 0];
            opts.StartPoint = [1 yCent 10];
            % Fit model to data.
            [fitresult_x, gof_x] = fit( xData, yData, ft, opts );
            
            %% fix y
            ydata = im(round(yCent),:)/max(im(round(yCent),:));
            yy = x(1,:);
            [xData, yData] = prepareCurveData( yy, ydata );
            % Set up fittype and options.
            ft = fittype( 'gauss1' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Display = 'Off';
            opts.Lower = [0 -Inf 0];
            opts.StartPoint = [1 xCent 10];
            % Fit model to data.
            [fitresult_y, gof_y] = fit( xData, yData, ft, opts );
            %%
            results.x = fitresult_x.c1*2.35/sqrt(2);
            results.y = fitresult_y.c1*2.35/sqrt(2);
            %%
            
            figH = figure('pos', [100 100 1000 500]);
            subplot(1,2,1)
            plot(xx - fitresult_x.b1, xdata, 'ob')
            hold on
            plot(xx - fitresult_x.b1, feval(fitresult_x,xx), 'LineWidth', 2)
            hold off
            xlim([-4*fitresult_x.c1 4*fitresult_x.c1])
            ylim([0 1.1])
            grid on;
            title(['Horizontal FWHM: ' num2str(fitresult_x.c1*2.35/sqrt(2))])
            
            subplot(1,2,2)
            plot(yy - fitresult_y.b1, ydata, 'ob')
            hold on
            plot(yy - fitresult_y.b1, feval(fitresult_y,yy), 'LineWidth', 2)
            hold off
            xlim([-4*fitresult_y.c1 4*fitresult_y.c1])
            ylim([0 1.1])
            grid on;
            title(['Horizontal FWHM: ' num2str(fitresult_y.c1*2.35/sqrt(2))])
            waitfor(figH)
            
        end
        
        
        function ExpValue = SetAutoExposure(app)

            counter = 1;
            Ncounter = 30;
            
            ExpLimits = app.ExposureEditField.Limits;
            app.src.AutoExposureTimeUpperLimit = ExpLimits(2);
            app.src.AutoExposureTimeLowerLimit = ExpLimits(1);
            app.src.AutoFunctionProfile = "MinimizeGain";
            app.src.AutoTargetBrightness = 0.8;

            while counter<=Ncounter
                app.src.ExposureAuto = "Once";
                pause(0.05);
                ExpValueTmp(counter) = app.src.ExposureTime;
                pause(0.1+ExpValueTmp(counter)/1e6);
                frame = get_cam_frame(app);
                pause(0.05);
                
                app.ExposureEditField.Value = round( ExpValueTmp(counter) );
                MaxVal = max(max(frame))
                if counter>1
                    TmpFlag = abs(ExpValueTmp(counter)-ExpValueTmp(counter-1))/ExpValueTmp(counter);
                    if TmpFlag<0.02
                        break
                    end
                end
                counter = counter + 1
            end
            app.src.ExposureAuto = "Off";
            ExpValue = ExpValueTmp(end);

%             % find the max exp time in log space
%             Nlog = 10;
%             ExpLimits = app.ExposureEditField.Limits;
%             tmp = logspace(log10(ExpLimits(1)), log10(ExpLimits(2)), Nlog);
%             flagtmp = 1;
%             ii=1;
%             while flagtmp
%                 app.src.ExposureTime = round(tmp(ii));
%                 pause(0.2+tmp(ii)/1e6);
%                 frame = get_cam_frame(app);
%                 MaxVal = max(max(frame));
%                 if MaxVal>4090
%                     flagtmp=0;
%                     MaxExpTime = tmp(ii);
%                 else
%                     ii = ii+1;
%                 end
%                 if ii>Nlog
%                     flagtmp=0;
%                     ii = ii-1;
%                     MaxExpTime = tmp(ii);
%                 end
%                 
%             end
%             if ii>1
%                 MinExpTime = tmp(ii-1);
%             else
%                 MinExpTime = ExpLimits(1);
%             end
%             
% 
%             ExpVal = linspace(MinExpTime, MaxExpTime, 100);
%             n = length(ExpVal);
%             left = 1;
%             right = n;
%             flag = 0;
%             
%             while left <= right
%                 mid = ceil((left + right) / 2);
%                 
%                 app.src.ExposureTime = round( ExpVal(mid) );
%                 app.ExposureEditField.Value = round( ExpVal(mid) );
%                 pause(0.2+round( ExpVal(mid) )/1e6);
%                 frame = get_cam_frame(app);
%                 
% %                 frame =  imgaussfilt(frame,1);
%                 MaxVal = max(max(frame));
%                 flag = 1;
%                 
%                 if abs(MaxVal-3800) <100
%                     flag = 1;
%                     ExpValue = ExpVal(mid);
%                     break;
%                 else if MaxVal > (3800+100)
%                         right = mid - 1;
%                 else if MaxVal < (3800-100)
%                         left = mid + 1;
%                         end
%                     end
%                 end
%                 ExpValue = ExpVal(mid);
%         
%         
%         
%             end
% 
%             if flag == 0
%                 app.ExposureEditField.Value = ExpVal(mid);
%                 app.src.ExposureTime = ExpVal(mid);
%                 ExpValue = ExpVal(mid);
%                 pause(0.1)
%             end

        end
        
        function [fitresult, gof] = createFit_M2(app, z, sy)
            %% Fit: 'untitled fit 1'.
            [xData, yData] = prepareCurveData( z, sy );

            % Set up fittype and options.
            ft = fittype( 'a+b*(x-c)^2', 'independent', 'x', 'dependent', 'y' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Display = 'Off';
            opts.Lower = [0 0 -25];
            opts.StartPoint = [0.03 0.001 0];
            opts.Upper = [5 1 25];

            % Fit model to data.
            [fitresult, gof] = fit( xData, yData, ft, opts );
        end
        
        
        function [D4sigmaX, D4sigmaY, imTmp, center, x, y] = calcMFD(app, im, pixel, thr)
            % im - image
            % pixel - pixel size of the camera, if you want measurement in pix unit,
            % set pixel=1
            % thr - initial threshold typicall thr =  2 
            
                [M, N] = size(im);
                [x, y] = meshgrid(-N/2:N/2-1, -M/2:M/2-1);
                x = x*pixel;
                y = y*pixel;
                xCent = sum(sum(x.*im))/sum(sum(im));
                yCent = sum(sum(y.*im))/sum(sum(im));
                D4sigmaX = 4*sqrt( sum(sum((x-xCent).^2.*im))/sum(sum(im)) );
                D4sigmaY = 4*sqrt( sum(sum((y-yCent).^2.*im))/sum(sum(im)) );
            %     thr = 5;
                flag = 1;
                while flag>0
                    imTmp = im;
                    imTmp(imTmp<thr) = 0;
                    xCent = sum(sum(x.*imTmp))/sum(sum(imTmp));
                    yCent = sum(sum(y.*imTmp))/sum(sum(imTmp));
                    D4sigmaXTmp = 4*sqrt( sum(sum((x-xCent).^2.*imTmp))/sum(sum(imTmp)) );
                    D4sigmaYTmp = 4*sqrt( sum(sum((y-yCent).^2.*imTmp))/sum(sum(imTmp)) );
                    thr = thr*1.02;
                    
%                     fprintf(1, 'D4sigmaX:%5.2f \t D4sigmaY:%5.2f \n',...
%                         D4sigmaXTmp, D4sigmaYTmp);
                    if D4sigmaXTmp/D4sigmaX>0.98 || D4sigmaYTmp/D4sigmaY>0.98 || thr>max(im(:))
                        center = [xCent yCent];
                        flag = 0;
                    end
                    D4sigmaX = D4sigmaXTmp;
                    D4sigmaY = D4sigmaYTmp;
                end
            %     teta = 0:0.01:2*pi;
            %     circx = D4sigmaX/2*sin(teta);
            %     circy = D4sigmaY/2*cos(teta);
            %     pcolor(x(1,:), y(:,1), imTmp);
            %     shading flat; colormap jet;
            %     hold on
            %     plot(circx+xCent, circy+yCent, 'LineWidth', 2, 'Color', 'y')
            %     hold off
            %     axis square;
        end


        function r = ReadELL(app)
            % Read data back from serial port
            r = '';
            while 1
                 if app.ELL.Port.NumBytesAvailable > 0
                     t = read(app.ELL.Port, app.ELL.Port.NumBytesAvailable,'char');
                     r = [r,t]; %#ok<AGROW>
                     if ~isempty(t) && t(end)==10
                         break
                     end
                 else
                     pause(0.01);
                 end
            end
%             while app.ELL.Port.NumBytesAvailable
%                 t = read(app.ELL.Port,1,'char');
%                 if ~isempty(t) && t(end)==newline
%                     break
%                 end
%             r = [r,t]; %#ok<AGROW>
%             end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartCam
        function StartCamButtonPushed(app, event)
            tmp = imaqhwinfo ('gentl', 1);
            if ~isempty(tmp.DeviceName)
                app.cam = videoinput("gentl", 1, "Mono12");
                triggerconfig(app.cam, 'manual');
                start(app.cam);

                app.src = getselectedsource(app.cam);
                tmpRes = get(app.cam, 'VideoResolution');
                app.cam_param.Width = tmpRes(2);
                app.cam_param.Height = tmpRes(1);
                
                % set the zoom correctly
                app.ZoomEditField.Value = min(tmpRes);
                app.ZoomEditField.Limits = [8, min(tmpRes)];

                app.CameraPanel.Title = ['Camera:' tmp.DeviceName];
                app.LampCam.Color = 'green';
            else
                app.CameraPanel.Title = ['Camera:' 'not found!'];
            end
            
        end

        % Button pushed function: ExitCam
        function ExitCamButtonPushed(app, event)
            if ~isempty(app.cam)
                delete(app.cam);
                app.LampCam.Color = 'red';
                app.cam = [];
                app.CameraPanel.Title = 'Camera';
            end
        end

        % Value changed function: ExposureEditField
        function ExposureEditFieldValueChanged(app, event)
            value = app.ExposureEditField.Value;
            if ~isempty(app.cam)
                app.src.ExposureTime = value;
            end
        end

        % Button pushed function: PreviewCamera
        function PreviewCameraButtonPushed(app, event)
            if ~isempty(app.cam)
                figH = figure;
                img_ax = axes;
                app.PrevFigH = figH;
                img_tmp = get_cam_frame(app);
                img = imagesc(img_ax,img_tmp);
                
                colormap(img_ax, app.DropDown.Value);
                set(gca, 'CLim', [0 4095]);
                colorbar;
                pbaspect([size(img_tmp,2) size(img_tmp,1) 1])
%                 set(gca, 'XTick', [])
%                 set(gca, 'YTick', [])
                if app.D4sCheckBox.Value
                    hold on
                    elip = plot(1:app.cam_param.Width, 1:app.cam_param.Width,...
                        'LineStyle','none', 'Marker','*', 'MarkerEdgeColor',[1 1 1],...
                        'MarkerSize', 3);
                    hold off
                    ylim=get(gca,'ylim');
                    xlim=get(gca,'xlim');
                    textData = {'D4\sigmaX: ', 'D4\sigmaY: '};
                    txtH = text(xlim(1)+2, ylim(1)+75, textData, 'FontSize',14,'Color','white');
                else
                    hold on
                    elip = plot(1:app.cam_param.Width, 1:app.cam_param.Width,...
                        'LineStyle','none', 'Marker','*', 'MarkerEdgeColor',[1 1 1],...
                        'MarkerSize', 3);
                    hold off
                    ylim=get(gca,'ylim');
                    xlim=get(gca,'xlim');
                    textData = ' ';
                    txtH = text(xlim(1)+2, ylim(1)+75, textData, 'FontSize',14,'Color','white');
                end


                while ishandle(figH)
                    frame = get_cam_frame(app);
                    if app.FFTCheckBox.Value
                        tmp = 10*log10( abs(fftshift(fft2(frame))) );
                        img.CData = tmp/max(tmp(:))*(2^12-1);
                    else
                        img.CData = frame;
                    end
           
                    
                    if app.D4sCheckBox.Value
                        D4sigma = im2mfd(app, frame);
                        elip.XData = D4sigma.xel;
                        elip.YData = D4sigma.yel;
                        txtH.String = {['D4\sigmaX: ' num2str(round(D4sigma.D4sigmaX))...
                            '(' num2str(round(D4sigma.D4sigmaX/1.699)) ')'],...
                            ['D4\sigmaY: ' num2str(round(D4sigma.D4sigmaY))...
                            '(' num2str(round(D4sigma.D4sigmaY/1.699)) ')']};
                    else
                        elip.XData = [];
                        elip.YData = [];
                        txtH.String = [];
                    end
                    
                    
                    drawnow;
                end
                app.PrevFigH = [];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.cam)
                app.cam.Exit;
                app.LampCam.Color = 'red';
                app.cam = [];
            end
            delete(app)
        end

        % Value changed function: ZoomEditField
        function ZoomEditFieldValueChanged(app, event)
            value = app.ZoomEditField.Value;
            if ~isempty(app.cam)
                tmpRes = get(app.cam, 'VideoResolution');
                MaxWidth = tmpRes(1);
                MaxHeight = tmpRes(2);
                zoom = value;
                Left = (MaxWidth - zoom)/2;
                Top = (MaxHeight - zoom)/2;
                stop(app.cam)
                app.cam.ROIPosition=[Left, Top, zoom, zoom];
                start(app.cam)
                
                if ishandle(app.PrevFigH)
                    xlim(gca, [1 zoom]);
                    ylim(gca, [1 zoom]);
                end
            end
        end

        % Button pushed function: AutoExposure
        function AutoExposureButtonPushed(app, event)
            if ~isempty(app.cam)
                ExpValue = SetAutoExposure(app);
            end
        end

        % Button pushed function: PreviewCamera_2
        function PreviewCamera_2ButtonPushed(app, event)
            if ~isempty(app.cam)
                for ii=1:app.NumberofframestotakeEditField.Value
                    frame(:,:,ii) = get_cam_frame(app);
                end
                fname = ['Cam_img_' datestr(now,'mm-dd-yyyy HH-MM-SS-FFF') '.mat'];
                save(fullfile(app.FilepathEditField.Value, fname), 'frame');
            end
        end

        % Button pushed function: FWHMCamera
        function FWHMCameraButtonPushed(app, event)
            if ~isempty(app.cam)
                FWHM = MakeGaussFit2image(app);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if ~isempty(app.PrevFigH)
                colormap(app.PrevFigH, value);
            end
        end

        % Value changed function: CircCheckBox
        function CircCheckBoxValueChanged(app, event)
            value = app.CircCheckBox.Value;
            if value
                
                app.ROI = drawcircle('Color','white');
                app.ROI.FaceAlpha = 0;
%                 app.ROI.MarkerSize = 1;
                app.ROI.LineWidth = 0.2;
            else
                app.ROI.Visible = 'off';
            end
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
            if ~isempty(app.cam)
                app.src.Gain = value;
            end
            
            
        end

        % Button pushed function: OpenELL
        function OpenELLButtonPushed(app, event)
            if isempty(app.ELL)
                app.ELL.Port = serialport(app.ComPortEditField.Value, 9600, 'DataBits',8,...
                    'StopBits',1,'Parity','none','FlowControl','none','Timeout',2);
                app.ELL.Adress = app.AdressEditField.Value;
                writeline(app.ELL.Port,[app.ELL.Adress 'in']);
                pause(0.1);
                tmpData = ReadELL(app);
                tmpData = tmpData(4:end-1);
                app.ELL.Info = struct('type', ['ELL',int2str(hex2dec(tmpData(1:2)))],...
                'serial_number',tmpData(3:10),'year_of_manufacture',tmpData(11:14),...
                'firmware',tmpData(15:16),'hardware_version',tmpData(17:18),...
                'travel',hex2dec(tmpData(19:22)),'pulse_per_mm',hex2dec(tmpData(23:end)));
                if ~isempty(tmpData)
                    app.ELLstatus.Text = {['Type: ' app.ELL.Info.type],...
                        ['Serial number: ' app.ELL.Info.serial_number],...
                        ['Year of manufacture: ' app.ELL.Info.year_of_manufacture],...
                        ['Firmware: ' app.ELL.Info.firmware],...
                        ['Hardware version: ' app.ELL.Info.hardware_version],...
                        ['Travel [mm]: ' num2str( app.ELL.Info.travel )],...
                        ['Pulse per mm: ' num2str( app.ELL.Info.pulse_per_mm )]};
                    app.ELLLamp.Color = 'Green';
                    app.GoTommEditField.Limits = [0, app.ELL.Info.travel];
                else
                    app.ELLstatus.Text = 'ELL stage not found!';
                end
            end
        end

        % Button pushed function: CloseELL
        function CloseELLButtonPushed(app, event)
            if ~isempty(app.ELL)
                delete(app.ELL.Port);
                app.ELLLamp.Color = 'Red';
                app.ELLstatus.Text = '';
                app.ELL = [];
            end
        end

        % Button pushed function: HomeELL
        function HomeELLButtonPushed(app, event)
            if ~isempty(app.ELL)
                writeline(app.ELL.Port,[app.ELL.Adress 'ho']);
                pause(0.5);
                tmpData = ReadELL(app);
                pos = double(typecast( uint32( hex2dec(tmpData(4:end-1)) ),'int32'))...
                    /app.ELL.Info.pulse_per_mm;
                app.PositionmmEditField.Value = pos;
            end
        end

        % Value changed function: GoTommEditField
        function GoTommEditFieldValueChanged(app, event)
            value = app.GoTommEditField.Value;
            if ~isempty(app.ELL)
                posPulse = round( value*app.ELL.Info.pulse_per_mm );
                writeline(app.ELL.Port,[app.ELL.Adress 'ma' dec2hex(posPulse,8)]);
                pause(0.2);
                tmpData = ReadELL(app);
                pos = double(typecast( uint32( hex2dec(tmpData(4:end-1)) ),'int32'))...
                    /app.ELL.Info.pulse_per_mm;
                app.PositionmmEditField.Value = pos;
            end
        end

        % Button pushed function: RunCalibrationButton
        function RunCalibrationButtonPushed(app, event)
            if ~isempty(app.ELL)
                if ~isempty(app.cam)
                    N = 10;
                    zRange = 6*app.RayleighrangemmEditField.Value;
                    if zRange<app.ELL.Info.travel
                        tmp = [-3, -2.5, -1, -0.5, 0, 0.5, 1, 2.5, 2.75, 3]*app.RayleighrangemmEditField.Value;
                        zpos = tmp+app.ELL.Info.travel/2;
                    else
                        
                        tmp = [-3, -2.5, -1, -0.5, 0, 0.5, 1, 2.5, 2.75, 3]*(app.ELL.Info.travel/6);
                        zpos = tmp+app.ELL.Info.travel/2;
                    end

                    %zpos = linspace(app.ELL.Info.travel/2-zRange/2,app.ELL.Info.travel/2+zRange/2, N);
                    figure('Position', [100 200 1000 500]);
                    for ii=1:N
                        posPulse = round( zpos(ii)*app.ELL.Info.pulse_per_mm );
                        writeline(app.ELL.Port,[app.ELL.Adress 'ma' dec2hex(posPulse,8)]);
                        pause(0.2);
                        tmpData = ReadELL(app);
                        pos = double(typecast( uint32( hex2dec(tmpData(4:end-1)) ),'int32'))...
                            /app.ELL.Info.pulse_per_mm;
                        app.PositionmmEditField.Value = pos;
                        pause(1)
                        
                        [garb] = SetAutoExposure(app);

                        frame = get_cam_frame(app);
                        D4sigma = im2mfd(app, frame);
                        

                        if ii==1
                            img_xlim = D4sigma.D4sigmaX;
                            img_ylim = D4sigma.D4sigmaY;
                        end
                        FullData.frame(:,:,ii) = frame;
                        FullData.sigmaX(ii) = D4sigma.D4sigmaX/2*app.CamerapixelsizeumEditField.Value/1000;
                        FullData.sigmaY(ii) = D4sigma.D4sigmaY/2*app.CamerapixelsizeumEditField.Value/1000;
                        FullData.zpos(ii) = (zpos(ii)-app.ELL.Info.travel/2)*2;
                        FullData.lambda = app.WavelengthumEditField.Value/1000;
                        subplot(2,N/2,ii)
                        imagesc(frame);
                        xlim([D4sigma.xCent-img_xlim, D4sigma.xCent+img_xlim])
                        ylim([D4sigma.yCent-img_ylim, D4sigma.yCent+img_ylim])
                        
                        colormap(app.DropDown.Value);
                        set(gca, 'CLim', [0 4095]);
                        subtitle(['z=' num2str( FullData.zpos(ii) ) 'mm']);
                        pbaspect([size(frame,2) size(frame,1) 1])
                        set(gca, 'XTick', [])
                        set(gca, 'YTick', [])
                        hold on
                        elip = plot(D4sigma.xel, D4sigma.yel,...
                            'LineStyle','none', 'Marker','*', 'MarkerEdgeColor',[1 1 1],...
                            'MarkerSize', 3);
                        hold off
                    end
                   [FullData.fitX, FullData.gofX] = createFit_M2(app,FullData.zpos, FullData.sigmaX.^2);
                   [FullData.fitY, FullData.gofY] = createFit_M2(app,FullData.zpos, FullData.sigmaY.^2);
                    
                   figure();
                    % Plot fit with data.
                    fsy = plot( FullData.fitY);
                    set(fsy, 'Color', [1,0,0])
                    set(fsy, 'LineWidth', 2)
                    set(fsy, 'LineStyle','-.')
                    hold on
                    plot( FullData.zpos, FullData.sigmaY.^2, 'LineStyle','none','Marker','o','MarkerSize', 8, 'MarkerEdgeColor','r')
                    hold off
                    
                    % Plot fit with data.
                    hold on
                    fsx = plot( FullData.fitX);
                    set(fsx, 'Color', [0,0,1])
                    set(fsx, 'LineWidth', 2)
                    set(fsx, 'LineStyle','-.')
                    hold on
                    plot( FullData.zpos, FullData.sigmaX.^2, 'LineStyle','none','Marker','o','MarkerSize', 8, 'MarkerEdgeColor','b')
                    hold off
                    % Label axes
                    xlabel( 'z', 'Interpreter', 'none' );
                    ylabel( 'w(z)', 'Interpreter', 'none' );
                    grid on
                    legend( {'fit w_y(z)', 'w_y(z)', 'fit w_x(z)', 'w_x(z)'},...
                        'Location', 'NorthEast', 'Interpreter', 'none' );
                    
                    
                    w0_y = sqrt(FullData.fitY.a);
                    M2_y = sqrt(FullData.fitY.b*(pi*w0_y/FullData.lambda).^2);
                    w0_y_pos = FullData.fitY.c;
                    
                    w0_x = sqrt(FullData.fitX.a);
                    M2_x = sqrt(FullData.fitX.b*(pi*w0_x/FullData.lambda).^2);
                    w0_x_pos = FullData.fitX.c;
                    
                    text(min(get(gca, 'Xlim'))*0.9,max(get(gca, 'Ylim'))*0.8,...
                        {['M2_y=' num2str(M2_y)], ['w0_y=' num2str(w0_y) '[mm]'], ...
                        ['zr_y=' num2str(pi*w0_y^2/FullData.lambda) '[mm]'], ['w0_y pos=' num2str(w0_y_pos) '[mm]']},'Color', 'red','FontWeight', 'bold')
                    text(max(get(gca, 'Xlim'))*0.4,max(get(gca, 'Ylim'))*0.8,...
                        {['M2_x=' num2str(M2_x)], ['w0_x=' num2str(w0_x) '[mm]'], ...
                        ['zr_x=' num2str(pi*w0_x^2/FullData.lambda) '[mm]'], ['w0_x pos=' num2str(w0_x_pos) '[mm]']},'Color', 'blue','FontWeight', 'bold')

                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.CameraPanel = uipanel(app.CameraTab);
            app.CameraPanel.Title = 'Camera';
            app.ExitCam = uibutton(app.CameraPanel, 'push');
            app.ExitCam.ButtonPushedFcn = createCallbackFcn(app, @ExitCamButtonPushed, true);
            app.ExitCam.Text = 'Close';
            app.ExposureEditFieldLabel = uilabel(app.CameraPanel);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.CameraPanel, 'numeric');
            app.ExposureEditField.Limits = [29 1000000];
            app.ExposureEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureEditFieldValueChanged, true);
            app.ExposureEditField.Value = 100;
            app.AutoExposure = uibutton(app.CameraPanel, 'push');
            app.AutoExposure.ButtonPushedFcn = createCallbackFcn(app, @AutoExposureButtonPushed, true);
            app.AutoExposure.Text = 'Auto';
            app.PreviewCamera = uibutton(app.CameraPanel, 'push');
            app.PreviewCamera.ButtonPushedFcn = createCallbackFcn(app, @PreviewCameraButtonPushed, true);
            app.PreviewCamera.Text = 'Preview';
            app.PreviewCamera_2 = uibutton(app.CameraPanel, 'push');
            app.PreviewCamera_2.ButtonPushedFcn = createCallbackFcn(app, @PreviewCamera_2ButtonPushed, true);
            app.PreviewCamera_2.Text = 'Save';
            app.ZoomEditFieldLabel = uilabel(app.CameraPanel);
            app.ZoomEditFieldLabel.Text = 'Zoom';
            app.ZoomEditField = uieditfield(app.CameraPanel, 'numeric');
            app.ZoomEditField.Limits = [0 10000];
            app.ZoomEditField.ValueDisplayFormat = '%.0f';
            app.ZoomEditField.ValueChangedFcn = createCallbackFcn(app, @ZoomEditFieldValueChanged, true);
            app.ZoomEditField.Value = 1800;
            app.D4sCheckBox = uicheckbox(app.CameraPanel);
            app.D4sCheckBox.Text = 'D4s';
            app.FilepathEditFieldLabel = uilabel(app.CameraPanel);
            app.FilepathEditFieldLabel.Text = 'File path';
            app.FilepathEditField = uieditfield(app.CameraPanel, 'text');
            app.FilepathEditField.Value = 'C:\Users\WiseGroupLab07\Desktop\Pavel';
            app.FWHMCamera = uibutton(app.CameraPanel, 'push');
            app.FWHMCamera.ButtonPushedFcn = createCallbackFcn(app, @FWHMCameraButtonPushed, true);
            app.FWHMCamera.Text = 'FWHM';
            app.DropDown = uidropdown(app.CameraPanel);
            app.DropDown.Items = {'jet', 'gray', 'hsv', 'hot', 'parula'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'jet';
            app.FFTCheckBox = uicheckbox(app.CameraPanel);
            app.FFTCheckBox.Text = 'FFT';
            app.GainEditFieldLabel = uilabel(app.CameraPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.CameraPanel, 'numeric');
            app.GainEditField.Limits = [0 36];
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.NumberofframestotakeEditFieldLabel = uilabel(app.CameraPanel);
            app.NumberofframestotakeEditFieldLabel.Text = 'Number of frames to take';
            app.NumberofframestotakeEditField = uieditfield(app.CameraPanel, 'numeric');
            app.NumberofframestotakeEditField.Limits = [1 1000000];
            app.NumberofframestotakeEditField.Value = 1;
            app.CircCheckBox = uicheckbox(app.CameraPanel);
            app.CircCheckBox.ValueChangedFcn = createCallbackFcn(app, @CircCheckBoxValueChanged, true);
            app.CircCheckBox.Text = 'Circ';
            app.StartCam = uibutton(app.CameraPanel, 'push');
            app.StartCam.ButtonPushedFcn = createCallbackFcn(app, @StartCamButtonPushed, true);
            app.StartCam.Text = 'Open';
            app.ElliptecTab = uitab(app.TabGroup);
            app.ElliptecTab.Title = 'Elliptec';
            app.OpenELL = uibutton(app.ElliptecTab, 'push');
            app.OpenELL.ButtonPushedFcn = createCallbackFcn(app, @OpenELLButtonPushed, true);
            app.OpenELL.Text = 'Open';
            app.CloseELL = uibutton(app.ElliptecTab, 'push');
            app.CloseELL.ButtonPushedFcn = createCallbackFcn(app, @CloseELLButtonPushed, true);
            app.CloseELL.Text = 'Close';
            app.ComPortEditFieldLabel = uilabel(app.ElliptecTab);
            app.ComPortEditFieldLabel.Text = 'ComPort';
            app.ComPortEditField = uieditfield(app.ElliptecTab, 'text');
            app.ComPortEditField.Value = 'COM7';
            app.ELLstatus = uilabel(app.ElliptecTab);
            app.ELLstatus.Text = '';
            app.HomeELL = uibutton(app.ElliptecTab, 'push');
            app.HomeELL.ButtonPushedFcn = createCallbackFcn(app, @HomeELLButtonPushed, true);
            app.HomeELL.Text = 'Home';
            app.PositionmmEditFieldLabel = uilabel(app.ElliptecTab);
            app.PositionmmEditFieldLabel.Text = 'Position [mm]';
            app.PositionmmEditField = uieditfield(app.ElliptecTab, 'numeric');
            app.PositionmmEditField.Editable = 'off';
            app.AdressEditFieldLabel = uilabel(app.ElliptecTab);
            app.AdressEditFieldLabel.Text = 'Adress';
            app.AdressEditField = uieditfield(app.ElliptecTab, 'text');
            app.AdressEditField.Value = '0';
            app.GoTommEditFieldLabel = uilabel(app.ElliptecTab);
            app.GoTommEditFieldLabel.Text = 'GoTo [mm]';
            app.GoTommEditField = uieditfield(app.ElliptecTab, 'numeric');
            app.GoTommEditField.Limits = [0 Inf];
            app.GoTommEditField.ValueChangedFcn = createCallbackFcn(app, @GoTommEditFieldValueChanged, true);
            app.M2Tab = uitab(app.TabGroup);
            app.M2Tab.Title = 'M2';
            app.WavelengthumEditFieldLabel = uilabel(app.M2Tab);
            app.WavelengthumEditFieldLabel.Text = 'Wavelength [um]';
            app.WavelengthumEditField = uieditfield(app.M2Tab, 'numeric');
            app.WavelengthumEditField.Value = 1.03;
            app.RunCalibrationButton = uibutton(app.M2Tab, 'push');
            app.RunCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunCalibrationButtonPushed, true);
            app.RunCalibrationButton.Text = 'Run Calibration';
            app.RunScanButton = uibutton(app.M2Tab, 'push');
            app.RunScanButton.Text = 'Run Scan';
            app.CamerapixelsizeumEditFieldLabel = uilabel(app.M2Tab);
            app.CamerapixelsizeumEditFieldLabel.Text = 'Camera pixel size [um]';
            app.CamerapixelsizeumEditField = uieditfield(app.M2Tab, 'numeric');
            app.CamerapixelsizeumEditField.Value = 3.45;
            app.RayleighrangemmEditFieldLabel = uilabel(app.M2Tab);
            app.RayleighrangemmEditFieldLabel.Text = 'Rayleigh range [mm]';
            app.RayleighrangemmEditField = uieditfield(app.M2Tab, 'numeric');
            app.RayleighrangemmEditField.Limits = [0 25];
            app.RayleighrangemmEditField.Value = 5;
            app.LampCam = uilamp(app.UIFigure);
            app.ELLLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CameraApp_PS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end